classdef PuffyPenguin_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PuffyPenguinUIFigure            matlab.ui.Figure
        timeElapsedLabel                matlab.ui.control.Label
        StopButton                      matlab.ui.control.Button
        WeightgSpinner                  matlab.ui.control.Spinner
        WeightgSpinnerLabel             matlab.ui.control.Label
        Water                           matlab.ui.control.Label
        Trial                           matlab.ui.control.Label
        PauseSwitch                     matlab.ui.control.Switch
        Subjectname                     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        AdjustmentTab                   matlab.ui.container.Tab
        SensorthresholdsPanel           matlab.ui.container.Panel
        readTouchThresholds             matlab.ui.control.Button
        TouchthresholdsdSpinner         matlab.ui.control.Spinner
        TouchthresholdsdSpinnerLabel    matlab.ui.control.Label
        UpdateThreshButton              matlab.ui.control.Button
        RightspoutSlider                matlab.ui.control.Slider
        RightspoutSliderLabel           matlab.ui.control.Label
        LeftspoutSlider                 matlab.ui.control.Slider
        LeftspoutSliderLabel            matlab.ui.control.Label
        SpoutadjustmentPanel            matlab.ui.container.Panel
        spoutOffset                     matlab.ui.control.NumericEditField
        OffsetLabel                     matlab.ui.control.Label
        RightSpoutEdit                  matlab.ui.control.NumericEditField
        RightEditFieldLabel             matlab.ui.control.Label
        LeftSpoutEdit                   matlab.ui.control.NumericEditField
        LeftEditFieldLabel              matlab.ui.control.Label
        WaterDispenserSpinner           matlab.ui.control.Spinner
        GiveBothWater                   matlab.ui.control.Button
        GiveLeftWater                   matlab.ui.control.Button
        GiveRightWater                  matlab.ui.control.Button
        spoutspeed                      matlab.ui.control.Spinner
        MovementdurationmsSpinnerLabel  matlab.ui.control.Label
        spouttoinner                    matlab.ui.control.Button
        spouttoouter                    matlab.ui.control.Button
        spouttozero                     matlab.ui.control.Button
        KnobmaxEditField                matlab.ui.control.NumericEditField
        KnobmaxEditFieldLabel           matlab.ui.control.Label
        SpoutRightKnob                  matlab.ui.control.Knob
        RightspoutKnobLabel             matlab.ui.control.Label
        SpoutLeftKnob                   matlab.ui.control.Knob
        LeftspoutKnobLabel              matlab.ui.control.Label
        SpoutMirrorspouts               matlab.ui.control.CheckBox
        SpoutsetouterButton             matlab.ui.control.Button
        SpoutsetinnerButton             matlab.ui.control.Button
        TaskTab                         matlab.ui.container.Tab
        RightspoutbiasSpinner           matlab.ui.control.Spinner
        RightspoutbiasSpinnerLabel      matlab.ui.control.Label
        LeftspoutbiasSpinner            matlab.ui.control.Spinner
        LeftspoutbiasSpinnerLabel       matlab.ui.control.Label
        fractionselfperformedPanel      matlab.ui.container.Panel
        fractionmixedSpinner            matlab.ui.control.Spinner
        fractionmixedSpinnerLabel       matlab.ui.control.Label
        fractiontactileSpinner          matlab.ui.control.Spinner
        fractiontactileSpinnerLabel     matlab.ui.control.Label
        fractionaudioSpinner            matlab.ui.control.Spinner
        fractionaudioSpinnerLabel       matlab.ui.control.Label
        fractionvisionSpinner           matlab.ui.control.Spinner
        fractionvisionSpinnerLabel      matlab.ui.control.Label
        TrainingPanel                   matlab.ui.container.Panel
        ShowWaterCheckBox               matlab.ui.control.CheckBox
        AssistedLamp                    matlab.ui.control.Lamp
        AssistedLampLabel               matlab.ui.control.Label
        AutorewardCheckBox              matlab.ui.control.CheckBox
        TrainingmodeCheckBox            matlab.ui.control.CheckBox
        TasksettingsPanel               matlab.ui.container.Panel
        distDifficulties                matlab.ui.control.EditField
        difficultiesEditFieldLabel      matlab.ui.control.Label
        maxDetectionSequence            matlab.ui.control.CheckBox
        targetEditField                 matlab.ui.control.EditField
        targetEditFieldLabel            matlab.ui.control.Label
        SomatossensorydistractorCheckBox  matlab.ui.control.CheckBox
        VisualdistractorCheckBox        matlab.ui.control.CheckBox
        AudiodistractorCheckBox         matlab.ui.control.CheckBox
        StimrateSpinner                 matlab.ui.control.Spinner
        FrequencyLabel                  matlab.ui.control.Label
        pRightSpinner                   matlab.ui.control.Spinner
        pRightSpinnerLabel              matlab.ui.control.Label
        pDistSpinner                    matlab.ui.control.Spinner
        pDistSpinnerLabel               matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        pSomatosensorySpinner           matlab.ui.control.Spinner
        pSomatosensorySpinnerLabel      matlab.ui.control.Label
        pVisualSpinner                  matlab.ui.control.Spinner
        pVisualSpinnerLabel             matlab.ui.control.Label
        pAudioSpinner                   matlab.ui.control.Spinner
        pAudioSpinnerLabel              matlab.ui.control.Label
        ModalityDropDown                matlab.ui.control.DropDown
        ModalityDropDownLabel           matlab.ui.control.Label
        distractorEditField             matlab.ui.control.EditField
        distractorEditFieldLabel        matlab.ui.control.Label
        psychometrics_ax                matlab.ui.control.UIAxes
        stimuli_ax                      matlab.ui.control.UIAxes
        trial_ax                        matlab.ui.control.UIAxes
        trial_performance_ax            matlab.ui.control.UIAxes
        SettingsTab                     matlab.ui.container.Tab
        optosequencePanel               matlab.ui.container.Panel
        optoSeqUnilateral               matlab.ui.control.CheckBox
        optoSeqStartTime                matlab.ui.control.NumericEditField
        startminLabel                   matlab.ui.control.Label
        optoSeqInterval                 matlab.ui.control.NumericEditField
        intervalminLabel                matlab.ui.control.Label
        optoSeqTrials                   matlab.ui.control.NumericEditField
        optotrialsLabel                 matlab.ui.control.Label
        optoSeqActive                   matlab.ui.control.CheckBox
        ServerpathEditField             matlab.ui.control.EditField
        ServerpathEditFieldLabel        matlab.ui.control.Label
        SavesettingsCheckBox            matlab.ui.control.CheckBox
        TasksettingsPanel_2             matlab.ui.container.Panel
        MaxtrialsonsamesideSpinner      matlab.ui.control.Spinner
        MaxtrialsonsamesideSpinnerLabel  matlab.ui.control.Label
        TrialstartsoundCheckBox         matlab.ui.control.CheckBox
        ITI_shape                       matlab.ui.control.Spinner
        shapeLabel                      matlab.ui.control.Label
        IntertrialintervalLabel         matlab.ui.control.Label
        rewardSizeSpinner               matlab.ui.control.Spinner
        RewardsizeuLLabel               matlab.ui.control.Label
        TimeoutSpinner                  matlab.ui.control.Spinner
        TimeoutsLabel                   matlab.ui.control.Label
        HappytimeSpinner                matlab.ui.control.Spinner
        TimetocollectrewardsLabel       matlab.ui.control.Label
        TimetoconfirmSpinner            matlab.ui.control.Spinner
        TimetoconfirmsLabel             matlab.ui.control.Label
        TimetochooseSpinner             matlab.ui.control.Spinner
        ITI_scale                       matlab.ui.control.Spinner
        TimetochoosesSpinnerLabel       matlab.ui.control.Label
        scaleLabel                      matlab.ui.control.Label
        StimulisettingsPanel            matlab.ui.container.Panel
        contingencyReversal             matlab.ui.control.CheckBox
        StimulusscreensEditField        matlab.ui.control.EditField
        StimulusscreensEditFieldLabel   matlab.ui.control.Label
        UseblackscreenCheckBox          matlab.ui.control.CheckBox
        Delay2valuesforminmaxEditField  matlab.ui.control.EditField
        Delay2valuesforminmaxEditFieldLabel  matlab.ui.control.Label
        StimCueCheckBox                 matlab.ui.control.CheckBox
        prestimdelay                    matlab.ui.control.Spinner
        PrestimdelaysLabel              matlab.ui.control.Label
        puffnessSpinner                 matlab.ui.control.Spinner
        puffnessSpinnerLabel            matlab.ui.control.Label
        loudnessSpinner                 matlab.ui.control.Spinner
        loudnessSpinnerLabel            matlab.ui.control.Label
        brightnessSpinner               matlab.ui.control.Spinner
        brightnessSpinnerLabel          matlab.ui.control.Label
        PuffdurmsSpinner                matlab.ui.control.Spinner
        PuffdurmsSpinnerLabel           matlab.ui.control.Label
        BeepdurmsSpinner                matlab.ui.control.Spinner
        BeepdurmsSpinnerLabel           matlab.ui.control.Label
        FlashdurmsSpinner               matlab.ui.control.Spinner
        FlashdurmsSpinnerLabel          matlab.ui.control.Label
        PunishdurationSpinner           matlab.ui.control.Spinner
        PunishdurationLabel             matlab.ui.control.Label
        StimulidurationSpinner          matlab.ui.control.Spinner
        StimulidurationsSpinnerLabel    matlab.ui.control.Label
        NetworktriggerPanel             matlab.ui.container.Panel
        labcamsaddressEditField         matlab.ui.control.EditField
        labcamsaddressEditFieldLabel    matlab.ui.control.Label
        widefieldaddressEditField       matlab.ui.control.EditField
        widefieldaddressEditFieldLabel  matlab.ui.control.Label
        optogeneticsPanel               matlab.ui.container.Panel
        optoProbBoth                    matlab.ui.control.NumericEditField
        bothprobabilityLabel            matlab.ui.control.Label
        optoProbRight                   matlab.ui.control.NumericEditField
        rightprobabilityLabel           matlab.ui.control.Label
        optoLocation                    matlab.ui.control.DropDown
        optoRampEditField               matlab.ui.control.NumericEditField
        rampdownsLabel                  matlab.ui.control.Label
        optoamp2EditField               matlab.ui.control.NumericEditField
        power2mwLabel                   matlab.ui.control.Label
        optoamp1EditField               matlab.ui.control.NumericEditField
        power1mWLabel                   matlab.ui.control.Label
        optoperiodDropDown              matlab.ui.control.DropDown
        optodurationEditField           matlab.ui.control.NumericEditField
        durationsLabel                  matlab.ui.control.Label
        optofractiontrialsEditField     matlab.ui.control.NumericEditField
        fractiontrialsEditFieldLabel    matlab.ui.control.Label
    end

    properties (Access = public)
        bpodtimer
        
    end
    properties (Access=private)
        
        nTrialsToShow int32
        trialPlot struct
        
    end
    
    methods (Access = public)
        function getSettingsFromBpod(app)
            set(app.PauseSwitch,'Value','pause')
            global BpodSystem
            S = BpodSystem.ProtocolSettings;
            set(app.Subjectname,'Text',S.SubjectName)
            if isfield(BpodSystem.Data,'animalWeight')
                set(app.WeightgSpinner,'Value',BpodSystem.Data.animalWeight)
            end
            set(app.WeightgSpinnerLabel,'FontColor','r')
            set(app.timeElapsedLabel,'Text','0.0 min')
            set(app.StopButton,'Text','Stop')
            set(app.Water,'Text','0.0 mL')
            set(app.Trial,'Text','initialized')
            %spouts and handles
            set(app.spoutspeed,'Value',BpodSystem.ProtocolSettings.SpoutSpeed)
            
            % touch thresh
            set(app.TouchthresholdsdSpinner,'Value',BpodSystem.ProtocolSettings.TouchThresh)
            %training mode
            set(app.TrainingmodeCheckBox,'Value',BpodSystem.ProtocolSettings.TrainingMode)
            set(app.AutorewardCheckBox,'Value',BpodSystem.ProtocolSettings.AutoReward)
            set(app.fractionmixedSpinner,'Value', BpodSystem.ProtocolSettings.fractionTrainingMixed)
            set(app.fractionaudioSpinner, 'Value', BpodSystem.ProtocolSettings.fractionTrainingAudio)
            set(app.fractionvisionSpinner, 'Value',BpodSystem.ProtocolSettings.fractionTrainingVision)
            set(app.fractiontactileSpinner, 'Value',BpodSystem.ProtocolSettings.fractionTrainingTactile)
            
            % modality/task
            set(app.ModalityDropDown,'Value',BpodSystem.ProtocolSettings.RewardedModality)
            set(app.pRightSpinner,'Value',BpodSystem.ProtocolSettings.ProbRight)
            set(app.pAudioSpinner,'Value',BpodSystem.ProtocolSettings.ProbAudio)
            set(app.pVisualSpinner,'Value',BpodSystem.ProtocolSettings.ProbVision)
            set(app.VisualdistractorCheckBox,'Value',BpodSystem.ProtocolSettings.useDistVisual)
            set(app.AudiodistractorCheckBox,'Value',BpodSystem.ProtocolSettings.useDistAudio)
            set(app.SomatossensorydistractorCheckBox,'Value',BpodSystem.ProtocolSettings.useDistTactile)
            
            set(app.pSomatosensorySpinner,'Value',BpodSystem.ProtocolSettings.ProbTactile)
            set(app.pDistSpinner,'Value',BpodSystem.ProtocolSettings.DistProb)
            set(app.StimrateSpinner,'Value',BpodSystem.ProtocolSettings.StimRate)
            set(app.targetEditField,'Value',num2str(BpodSystem.ProtocolSettings.TargFractions))
            set(app.distractorEditField,'Value',num2str(BpodSystem.ProtocolSettings.DistFractions))
            set(app.StimulidurationSpinner,'Value',BpodSystem.ProtocolSettings.StimDuration)
            set(app.PunishdurationSpinner,'Value',BpodSystem.ProtocolSettings.PunishSoundDur)
            set(app.MaxtrialsonsamesideSpinner,'Value',BpodSystem.ProtocolSettings.biasSeqLength)
            set(app.distDifficulties,'Value',num2str(BpodSystem.ProtocolSettings.distDifficulties))
            set(app.maxDetectionSequence,'Value',logical(BpodSystem.ProtocolSettings.maxDetectionSequence))
            set(app.contingencyReversal,'Value',logical(BpodSystem.ProtocolSettings.contingencyReversal))
            
            set(app.TrialstartsoundCheckBox,'Value',BpodSystem.ProtocolSettings.TrialStartCue)
            set(app.TimetochooseSpinner,'Value',BpodSystem.ProtocolSettings.TimeToChoose)
            set(app.TimetoconfirmSpinner,'Value',BpodSystem.ProtocolSettings.TimeToConfirm)
            set(app.HappytimeSpinner,'Value',BpodSystem.ProtocolSettings.happyTime)
            set(app.TimeoutSpinner,'Value',BpodSystem.ProtocolSettings.TimeOut)
            set(app.rewardSizeSpinner,'Value',BpodSystem.ProtocolSettings.leftRewardVolume)
            set(app.ITI_scale,'Value',BpodSystem.ProtocolSettings.ITI_scale)
            set(app.prestimdelay,'Value',BpodSystem.ProtocolSettings.preStimDelay)
            set(app.Delay2valuesforminmaxEditField,'Value',num2str(BpodSystem.ProtocolSettings.DecisionGap))
            set(app.StimCueCheckBox ,'Value',BpodSystem.ProtocolSettings.UseStimStartCue)
            set(app.spoutOffset ,'Value',BpodSystem.ProtocolSettings.spoutOffset)
            
            if app.FlashdurmsSpinner.Limits(2) < BpodSystem.ProtocolSettings.FlashDuration
                app.FlashdurmsSpinner.Limits(2) = BpodSystem.ProtocolSettings.FlashDuration + 1;
            end
            set(app.FlashdurmsSpinner,'Value',BpodSystem.ProtocolSettings.FlashDuration)
            set(app.BeepdurmsSpinner,'Value',BpodSystem.ProtocolSettings.BeepDuration)
            set(app.PuffdurmsSpinner,'Value',BpodSystem.ProtocolSettings.BuzzDuration)
            
            set(app.StimulusscreensEditField,'Value', num2str(BpodSystem.ProtocolSettings.stimScreens))
            set(app.UseblackscreenCheckBox,'Value', BpodSystem.ProtocolSettings.blackScreen)
            set(app.ShowWaterCheckBox,'Value', BpodSystem.ProtocolSettings.showWater)
            
            set(app.brightnessSpinner,'Value',BpodSystem.ProtocolSettings.StimBrightness)
            set(app.loudnessSpinner,'Value',BpodSystem.ProtocolSettings.StimLoudness)
            set(app.puffnessSpinner,'Value',BpodSystem.ProtocolSettings.BuzzStrength)
            
            if isfield(BpodSystem.ProtocolSettings,'labcamsWidefield')
                set(app.widefieldaddressEditField,'Value',BpodSystem.ProtocolSettings.labcamsWidefield)
            end
            
            set(app.labcamsaddressEditField,'Value',BpodSystem.ProtocolSettings.labcamsAddress)
            set(app.ITI_scale,'Value',BpodSystem.ProtocolSettings.ITI_scale)
            set(app.ITI_shape,'Value',BpodSystem.ProtocolSettings.ITI_shape)
            
            if ~isfield(BpodSystem.ProtocolSettings,'SaveSettings')
                BpodSystem.ProtocolSettings.SaveSettings = true;
            end
            set(app.SavesettingsCheckBox,'Value',BpodSystem.ProtocolSettings.SaveSettings);
            set(app.ServerpathEditField,'Value',BpodSystem.ProtocolSettings.serverPath);
            
            %optogenetics
            set(app.optofractiontrialsEditField,'Value',BpodSystem.ProtocolSettings.optoProb)
            set(app.optodurationEditField,'Value',BpodSystem.ProtocolSettings.optoDur)
            set(app.optoperiodDropDown,'Value',BpodSystem.ProtocolSettings.optoPeriod)
            set(app.optoamp1EditField,'Value',BpodSystem.ProtocolSettings.optoAmp1)
            set(app.optoamp2EditField,'Value',BpodSystem.ProtocolSettings.optoAmp2)
            set(app.optoRampEditField,'Value',BpodSystem.ProtocolSettings.optoRamp)
            set(app.optoLocation,'Value',BpodSystem.ProtocolSettings.optoLocation)
            set(app.optoProbRight,'Value',BpodSystem.ProtocolSettings.optoRight)
            set(app.optoProbBoth,'Value',BpodSystem.ProtocolSettings.optoBoth)
            set(app.optoSeqActive,'Value',BpodSystem.ProtocolSettings.optoSeqActive)
            set(app.optoSeqUnilateral,'Value',BpodSystem.ProtocolSettings.optoSeqUnilateral)
            set(app.optoSeqTrials,'Value',BpodSystem.ProtocolSettings.optoSeqTrials)
            set(app.optoSeqInterval,'Value',BpodSystem.ProtocolSettings.optoSeqInterval)
            set(app.optoSeqStartTime,'Value',BpodSystem.ProtocolSettings.optoSeqStartTime)
            
            %try
            %    start(app.bpodtimer)
            %catch
            %app.bpodtimer = timer('Period',0.033,...
            %   'ExecutionMode', 'fixedSpacing', ...
            %   'TasksToExecute', Inf);
            %app.bpodtimer.TimerFcn = @app.timerfunction;
            %start(app.bpodtimer)
            %end
            
        end
    end
    
    methods (Access = public)
        
        function results = moveSpouts(app,l,r)
            set(app.LeftSpoutEdit,'Value',l); %update indicator
            set(app.SpoutLeftKnob,'Value',l); %update slider
            set(app.RightSpoutEdit,'Value',r); %update indicator
            set(app.SpoutRightKnob,'Value',r);  %update slider
            ls = num2str(l);rs = num2str(r); drawnow;
            teensyWrite([71 length(ls)  ls length(rs)  rs]); %move the spouts
        end
        
        function results = sendTeensyStepperTrialInfo(app)
            % this might be simplified in the teeny side..
            global BpodSystem
            S = BpodSystem.ProtocolSettings;
            setMotorPositions;
            
        end
        
        function results = moveHandles(app,v)
            set(app.HandlepositionKnob,'Value',v); %update slider
            v = num2str(int32(floor(v)));
            teensyWrite([72 length(v)  v]); %move the spouts
        end
        
        function results = prepareTrial(app,triallist)
            global BpodSystem
            
            itrial = BpodSystem.Data.cTrial;
            ncompleted = 0;
            
            if itrial>1
                
                elapsed = (BpodSystem.Data.TrialStartTime(end) - BpodSystem.Data.TrialStartTime(1))/60;
                set(app.timeElapsedLabel,'Text',sprintf('%3.1f min',elapsed))
                
                % Get how much water the mouse got
                left_reward = [BpodSystem.Data.TrialSettings.leftRewardVolume];
                left_reward(BpodSystem.Data.CorrectSide == 2) = 0;
                right_reward = [BpodSystem.Data.TrialSettings.leftRewardVolume];
                right_reward(BpodSystem.Data.CorrectSide == 1) = 0;
                rewarded = BpodSystem.Data.Rewarded;
                autorewarded = BpodSystem.Data.AutoReward;
                water_ul = sum((left_reward + right_reward).*(rewarded+autorewarded))/1000.;
                %set the trial number
                set(app.Water,'Text',sprintf("Water %1.3f ml (%d)",water_ul,sum(rewarded)))
                ncompleted = sum(~BpodSystem.Data.DidNotChoose);
            end
            set(app.Trial,'Text',sprintf("%d/%d complete", ncompleted,itrial));
            app.update_trial_plots(triallist)
            
            % show stimulus and trial timing
            %             modLabels = {'Aud' 'Vis' 'Tac' 'Opto'};
            modColors = [0 1 0; 0 0 0; 1 0 0; 0 0 1];
            trialEpisodes = cumsum(BpodSystem.GUIData.TrialEpisodes);
            %             axes(app.stimuli_ax);
            cla(app.stimuli_ax);hold(app.stimuli_ax,'on');
            offset = 1;
            N = length(BpodSystem.GUIData.Stimuli(1,:));
            x = trialEpisodes(1) + (0:N-1)/BpodSystem.ProtocolSettings.sRate;
            
            %audio
            y = BpodSystem.GUIData.Stimuli(1,:)/10 + 0.5;
            plot(app.stimuli_ax,x,y + 0*offset, 'Color', modColors(1,:)/2);
            y = BpodSystem.GUIData.Stimuli(2,:)/10 + 0.5;
            plot(app.stimuli_ax, [0 x(1)], [0.5 0.5], 'Color', modColors(1,:));
            plot(app.stimuli_ax, [x(end) trialEpisodes(end)], [0.5 0.5], 'Color', modColors(1,:));
            plot(app.stimuli_ax,x,y + 0*offset, 'Color', modColors(1,:));
            
            %vision
            y = BpodSystem.GUIData.Stimuli(3,:)/10 + 0.5;
            plot(app.stimuli_ax,x,y + 1*offset, 'Color', modColors(2,:) / 2);
            y = BpodSystem.GUIData.Stimuli(4,:)/10 +0.5;
            plot(app.stimuli_ax, [0 x(1)], [0.5 0.5] + 1*offset, 'Color', modColors(2,:));
            plot(app.stimuli_ax, [x(end) trialEpisodes(end)], [0.5 0.5] + 1*offset, 'Color', modColors(2,:));
            plot(app.stimuli_ax,x,y + 1*offset, 'Color', modColors(2,:));
            
            %somato
            y = BpodSystem.GUIData.Stimuli(5,:)/10 + 0.5;
            plot(app.stimuli_ax,x,y + 2*offset, 'Color', modColors(3,:) / 2);
            y = BpodSystem.GUIData.Stimuli(6,:)/10 +0.5;
            plot(app.stimuli_ax, [0 x(1)], [0.5 0.5] + 2*offset, 'Color', modColors(3,:));
            plot(app.stimuli_ax, [x(end) trialEpisodes(end)], [0.5 0.5] + 2*offset, 'Color', modColors(3,:));
            plot(app.stimuli_ax,x,y + 2*offset, 'Color', modColors(3,:));
            
            %opto
            y = BpodSystem.GUIData.Stimuli(7,:)/10 + 0.5;
            plot(app.stimuli_ax,x,y + 3*offset, 'Color', modColors(4,:)/2)
            y = BpodSystem.GUIData.Stimuli(8,:)/10 +0.5;
            plot(app.stimuli_ax, [0 x(1)], [0.5 0.5] + 3*offset, 'Color', modColors(4,:));
            plot(app.stimuli_ax, [x(end) trialEpisodes(end)], [0.5 0.5] + 3*offset, 'Color', modColors(4,:));
            plot(app.stimuli_ax,x,y + 3*offset, 'Color', modColors(4,:))
            
            for xx = 1 : length(trialEpisodes)
                plot(app.stimuli_ax, [trialEpisodes(xx),trialEpisodes(xx)],app.stimuli_ax.YLim, 'Color', [0.75 0.75 0.75]);
            end
            xlim(app.stimuli_ax,[-0.1, trialEpisodes(end)])
            ylim(app.stimuli_ax,[0,4])
            
            %%
            drawnow;
        end
        function results = init_plots(app)
            
            % update the trial axis
            app.nTrialsToShow  = 60;
            %             axes(app.trial_ax);
            cla(app.trial_ax); % this can be faster if re-used
            x = 1:app.nTrialsToShow; y = nan(app.nTrialsToShow,1);
            FUTURE_COLOR = [0.5,0.5,0.5];
            app.trialPlot = struct('FutureTrialLine',plot(app.trial_ax,x,y,'LineStyle','none','Marker','o','MarkerEdge','none','MarkerFace',FUTURE_COLOR, 'MarkerSize',6));
            hold(app.trial_ax,'on');
            app.trialPlot.CurrentTrialCircle = plot(app.trial_ax,1,nan, 'LineStyle','none','Marker','o','MarkerEdge','k','MarkerFace','w', 'MarkerSize',6);
            app.trialPlot.CurrentTrialCross = plot(app.trial_ax,1,nan, 'LineStyle','none','Marker','+','MarkerEdge','k','MarkerFace','w', 'MarkerSize',6);
            app.trialPlot.PunishedErrorLine = plot(app.trial_ax,1,nan, 'LineStyle','none','Marker','o','MarkerEdge','k','MarkerFace','r', 'MarkerSize',6);
            app.trialPlot.RewardedCorrectLine = plot(app.trial_ax,1,nan, 'LineStyle','none','Marker','o','MarkerEdge','k','MarkerFace','g', 'MarkerSize',6);
            app.trialPlot.AssistedCorrectLine = plot(app.trial_ax,1,nan, 'LineStyle','none','Marker','o','MarkerEdge',[0.75 0.75 0.75], 'MarkerFace','g', 'MarkerSize',6);
            app.trialPlot.AbortedLine = plot(app.trial_ax,1,nan, 'LineStyle','none','Marker','o','MarkerEdge',[1 .5 0],'MarkerFace',[1 .5 0], 'MarkerSize',6);
            app.trialPlot.NoResponseLine = plot(app.trial_ax,1,nan, 'LineStyle','none','Marker','o','MarkerEdge','b','MarkerFace','w', 'MarkerSize',6);
            app.trialPlot.NoLeverResponseLine = plot(app.trial_ax,1,nan, 'LineStyle','none','Marker','o','MarkerEdge','c','MarkerFace','w', 'MarkerSize',6);
            set(app.trial_ax,'TickDir','out','YLim', [-1.5, 2.5], 'YTick', [0 1],'YTickLabel', {'L','R'});
            % update the performance trial plot
            set(app.trial_performance_ax,'TickDir', 'in','YLim', [-0.1, 1.1], 'YTick',0:0.25:1);%ylabel(app.trial_performance_ax,'Performance'); %rescale y-axis and label
            set(app.psychometrics_ax,'TickDir', 'in','YLim', [-0.1, 1.1], 'YTick',0:0.25:1);ylabel(app.psychometrics_ax,'Performance'); %rescale y-axis and label
            % update stimuli plots
            ylabel(app.stimuli_ax,'');
            yticks(app.stimuli_ax,[0.5,1.5,2.5,3.5]);
            yticklabels(app.stimuli_ax,{'audio','visual','tactile','opto'});
            xlabel(app.stimuli_ax,'time (s)');
            
        end
        function update_trial_plots(app,trialslist)
            global BpodSystem
            itrial = BpodSystem.Data.cTrial;
            [mn,mx] = app.rescale_trial_ax(app.trial_ax,itrial);
            set(app.trialPlot.FutureTrialLine,'xdata',itrial:mx,'ydata',trialslist(itrial:mx));
            %Plot current trial
            set(app.trialPlot.CurrentTrialCircle, 'xdata', itrial, 'ydata', trialslist(itrial));
            set(app.trialPlot.CurrentTrialCross, 'xdata',    itrial, 'ydata', trialslist(itrial));
            set(app.AssistedLamp,'Enable',BpodSystem.ProtocolSettings.SingleSpoutTrial)
            %Plot past trials
            rewarded = BpodSystem.Data.Rewarded;
            assisted = BpodSystem.Data.Assisted;
            if ~isempty(rewarded)
                pltidx = mn:itrial-1;
                % get these from the Bpod data
                %Plot Error, punished
                wrongidx = find(rewarded(pltidx) == 0);
                x = pltidx(wrongidx);
                set(app.trialPlot.PunishedErrorLine, 'xdata', x, 'ydata', trialslist(x));
                %Plot Correct, rewarded
                correctidx = find(rewarded(pltidx) == 1 & assisted(pltidx) == 1);
                x = pltidx(correctidx);
                set(app.trialPlot.RewardedCorrectLine, 'xdata', x, 'ydata', trialslist(x));
                %Plot Correct, Assisted
                correctAssistidx = find(rewarded(pltidx) == 1 & assisted(pltidx) == 0);
                x = pltidx(correctAssistidx);
                set(app.trialPlot.AssistedCorrectLine, 'xdata', x, 'ydata', trialslist(x));
                %Plot DidNotChoose
                nochoiceidx = find(BpodSystem.Data.DidNotChoose(pltidx)==1);
                x = pltidx(nochoiceidx);
                set(app.trialPlot.NoResponseLine, 'xdata', x, 'ydata',trialslist(x));
                %                 %Plot DidNotLever
                %                 nostimidx = find(BpodSystem.Data.DidNotLever(pltidx)==1);
                %                 x = pltidx(nostimidx);
                %                 set(app.trialPlot.NoLeverResponseLine, 'xdata', x, 'ydata', trialslist(x));
            end
        end
        
        function results = update_performance_plots(app)
            ax = app.trial_performance_ax; cla(ax);
            global BpodSystem
            iTrial = BpodSystem.Data.cTrial;
            N = length(BpodSystem.Data.Rewarded); % ntrials
            [mn,mx] = app.rescale_trial_ax(ax,iTrial);hold(ax, 'on');%rescale x-axis and hold
            set(ax,'TickDir', 'out','YLim', [-0.1, 1.1], 'YTick', 0:0.25:1); ylabel(ax,'Performance'); %rescale y-axis and label
            ntrials_avg = 10; %number of trials to be used when averaging performance
            
            %compute performance values
            %%
            iAll = (BpodSystem.Data.Assisted) & (~BpodSystem.Data.DidNotChoose);
            iRight = (BpodSystem.Data.CorrectSide==2) & iAll;
            iLeft = (BpodSystem.Data.CorrectSide == 1) & iAll;
            
            PerformanceVec = cumsum(BpodSystem.Data.Assisted & BpodSystem.Data.Rewarded) ./cumsum(iAll);
            if sum(iAll)<=ntrials_avg*2
                sPerformanceVec = PerformanceVec;
            else %get performance for the last ntrials_avg trials
                sPerformanceVec = BpodSystem.Data.Performance;
                temp = find(iAll); iAll = false(1,length(iAll)); iAll(temp(end-(ntrials_avg*2)+1:end)) = true; clear temp %only use the last ntrials_avg trials
                sPerformanceVec(end+1) = sum(BpodSystem.Data.Rewarded(iAll))/(ntrials_avg*2); % add performance in current trial
            end
            BpodSystem.Data.Performance = sPerformanceVec; %update Bpod data with overall performance
            
            if sum(iLeft) <= ntrials_avg %if more than ntrials_avg trials on the left were performed
                temp = BpodSystem.Data.Rewarded; temp(~iLeft) = 0;
                lPerformanceVec = cumsum(temp)./cumsum(iLeft); %compute performance vector
                clear temp
            else
                lPerformanceVec = BpodSystem.Data.lPerformance;
                temp = find(iLeft); iLeft = false(1,length(iLeft)); iLeft(temp(end-ntrials_avg+1:end)) = true; clear temp %only use the last ntrials_avg trials
                lPerformanceVec(end+1) = sum(BpodSystem.Data.Rewarded(iLeft))/ntrials_avg; % add performance in current trial
            end
            BpodSystem.Data.lPerformance = lPerformanceVec; %update Bpod data with left performance
            
            if sum(iRight) <= ntrials_avg %if more than ntrials_avg trials on the left were performed
                temp = BpodSystem.Data.Rewarded; temp(~iRight) = 0;
                rPerformanceVec = cumsum(temp)./cumsum(iRight); %compute performance vector
                clear temp
            else
                rPerformanceVec = BpodSystem.Data.rPerformance;
                temp = find(iRight); iRight = false(1,length(iRight)); iRight(temp(end-ntrials_avg+1:end)) = true; clear temp %only use the last ntrials_avg trials
                rPerformanceVec(end+1) = sum(BpodSystem.Data.Rewarded(iRight))/ntrials_avg; % add performance in current trial
            end
            BpodSystem.Data.rPerformance = rPerformanceVec; %update Bpod data with right performance
            
            % plot
            plot(ax,[mn-1,mx+1],0.5*ones(1,2),'k-',"LineWidth",0.5); %plot line at 0.5
            h1 = plot(ax,PerformanceVec(1:iTrial),'-o','MarkerFaceColor',[0.5 0.5 0.5],'Color',[0.5 0.5 0.5],'MarkerSize',5); %plot performance
            h2 = plot(ax,sPerformanceVec(1:iTrial),'-o','MarkerFaceColor','k','Color','k','MarkerSize',4); %plot performance
            h3 = plot(ax,lPerformanceVec(1:iTrial),'-o','MarkerFaceColor','g','Color','g','MarkerSize',3); %left
            h4 = plot(ax,rPerformanceVec(1:iTrial),'-o','MarkerFaceColor','r','Color','r','MarkerSize',2); %right
            legend(ax,[h1 h2 h3 h4],{'GrandAvg','Both','Left','Right'},'Location','east','Orientation','vertical') %add legend
            xlabel(ax,'Trial #')
            hold(ax, 'off');
            
            % this needs to plot the psychometric curve for all distractor values
            ax = app.psychometrics_ax;cla(ax);
            set(ax,'TickDir', 'out','YLim', [0, 1], 'YTick', 0:0.25:1);
            stimsidevalues = diff(BpodSystem.Data.StimSideValues,[],1);
            detectIdx = any(BpodSystem.Data.StimSideValues == 0, 1); %these are trials where only one side had stimuli
            stimsidevalues(detectIdx) = median(stimsidevalues(detectIdx));
            uniquestimsides = unique(stimsidevalues);

            modalities = unique(BpodSystem.Data.StimType);
            perform = zeros(length(modalities)+1,length(uniquestimsides));
            modColors = [0.5 0.5 0.5; 0 0 0; 0 1 0; 1 0 0.5; 1 0 0; 1 0.5 0; 0 0.5 1];
            modLabels = {'All' 'Vis' 'Aud' 'AV' 'Tac' 'VT' 'AT'};
            for i = 1:length(uniquestimsides)
                
                cIdx = (stimsidevalues == uniquestimsides(i) & (BpodSystem.Data.Assisted & ~BpodSystem.Data.DidNotChoose));
                perform(1,i) = sum(BpodSystem.Data.Rewarded(cIdx))/sum(cIdx); % total performance
                
                for x = 1 : length(modalities)
                    modIdx = cIdx & BpodSystem.Data.StimType == modalities(x);
                    perform(1+x, i) = sum(BpodSystem.Data.Rewarded(modIdx))/sum(modIdx); % total performance for current modality
                end
            end
            plotIdx = [1 modalities+1]; %index for colors and legend
            plot(ax,[min(uniquestimsides)-1,max(uniquestimsides)+1],[.5,.5],'k-',"LineWidth",0.1)
            hold(ax,'on')
            
            clear cPlot
            for x = 1 : length(plotIdx)
                cPlot(x) = plot(ax,uniquestimsides(~isnan(perform(x,:))),perform(x,~isnan(perform(x,:))),'--ko','MarkerFaceColor', modColors(plotIdx(x),:),'MarkerSize',6);
            end
            set(ax,'XLim',[min(uniquestimsides)-2,max(uniquestimsides)+2],'XTick', uniquestimsides);
            set(ax,'YLim', [0.45 1]);
            ylabel(ax,'Prob correct choice'); %rescale y-axis and label
            xlabel(ax,'Distractor-Target difference')
            hold(ax,'off')
            legend(ax, cPlot, modLabels(plotIdx), 'location', 'northeastoutside', 'fontsize', 10);
            grid(ax, 'on');
            
        end
        
    end
    methods (Access = private)
        function [mn,mx] = rescale_trial_ax(app,ax,itrial) % helper for the trial_ax
            FractionWindowStickpoint = .75; % After this fraction of visible trials, the trial position in the window "sticks" and the window begins to slide through trials.
            mn = max(round(itrial - FractionWindowStickpoint*app.nTrialsToShow),1);
            mx = mn + app.nTrialsToShow - 1;
            set(ax,'XLim',[mn-1 mx+1]);
        end
        
        function results = timerfunction(app,x,y)
            global BpodSystem
            % then we have the names
            set(app.LLamp,'Color',[.5,.5,.5])
            set(app.RLamp,'Color',[.5,.5,.5])
            set(app.handlesLamp,'Color',[.5,.5,.5])
            if ~isempty(BpodSystem.StateMachineInfo.EventNames)
                try
                    event = BpodSystem.StateMachineInfo.EventNames{BpodSystem.Status.LastEvent};
                    if strcmp(event,'TouchShaker1_1') || strcmp(event,'TouchShaker1_3')
                        set(app.LLamp,'Color',[.5,1,.5])
                    elseif strcmp(event,'TouchShaker1_2') || strcmp(event,'TouchShaker1_4')
                        set(app.RLamp,'Color',[.0,1,.5])
                    elseif strcmp(event,'TouchShaker1_5') || strcmp(event,'TouchShaker1_6') || strcmp(event,'TouchShaker1_7') ...
                            strcmp(event,'TouchShaker1_8') || strcmp(event,'TouchShaker1_9') || strcmp(event,'TouchShaker1_10')
                    set(app.handlesLamp,'Color',[0,0,1])
                    end
                end
            end
        end
        
        function results = giveWater(app,valve)
            global BpodSystem
            
            waterAmount = app.WaterDispenserSpinner.Value; % give water for the regular time
            LeftValveTime = GetValveTimes(waterAmount, 1);
            RightValveTime = GetValveTimes(waterAmount, 2);
            
            if strcmp(valve,'left')
                valve = 1;
                cValveDur = LeftValveTime;
            elseif strcmp(valve,'right')
                valve = 2;
                cValveDur = RightValveTime;
            else
                valve = 3;
                cValveDur = max([LeftValveTime,RightValveTime]);
            end
            
            if ~BpodSystem.Status.InStateMatrix
                smavalve = NewStateMachine;
                smavalve = AddState(smavalve, 'Name', 'test', ...
                    'Timer', cValveDur,...
                    'StateChangeConditions', {'Tup', 'exit'},...
                    'OutputActions', {'ValveState', valve}); % Use 3 for valve 1 and 2 (1100) 5 for (1010)
                
                SendStateMachine(smavalve);
                pause(0.1);
                RunStateMachine();
                
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function HandlemaxEditFieldValueChanged(app, event)
            value = app.HandlemaxEditField.Value;
            app.HandlepositionKnob.Limits = [0,value];
        end

        % Value changed function: TrainingmodeCheckBox
        function TrainingmodeCheckBoxValueChanged(app, event)
            value = app.TrainingmodeCheckBox.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.TrainingMode = value;
            if ~value
                set(app.AutorewardCheckBox,'Enable','off')
                BpodSystem.ProtocolSettings.AutoReward = value;
            else
                set(app.AutorewardCheckBox,'Enable','on')
            end
        end

        % Value changed function: PauseSwitch
        function PauseSwitchValueChanged(app, event)
            value = app.PauseSwitch.Value;
            if value == "pause"
                value = true;
            else
                value = false;
            end
            global BpodSystem
            BpodSystem.Status.PuffyPenguinPause = value;
        end

        % Button pushed function: spouttozero
        function spouttozeroButtonPushed(app, event)
            moveSpouts(app,0,0)
        end

        % Button pushed function: spouttoouter
        function spouttoouterButtonPushed(app, event)
            global BpodSystem
            leftSpout = BpodSystem.ProtocolSettings.lOuterLim; %get left inner limit
            rightSpout = BpodSystem.ProtocolSettings.rOuterLim; %get right inner limit
            app.moveSpouts(leftSpout,rightSpout);
        end

        % Button pushed function: spouttoinner
        function spouttoinnerButtonPushed(app, event)
            global BpodSystem
            leftSpout = BpodSystem.ProtocolSettings.lInnerLim; %get left inner limit
            rightSpout = BpodSystem.ProtocolSettings.rInnerLim; %get right inner limit
            app.moveSpouts(leftSpout,rightSpout)
        end

        % Button pushed function: SpoutsetinnerButton
        function SpoutsetinnerButtonPushed(app, event)
            global BpodSystem
            BpodSystem.ProtocolSettings.lInnerLim = ceil(app.SpoutLeftKnob.Value); %get left inner limit
            BpodSystem.ProtocolSettings.rInnerLim = ceil(app.SpoutRightKnob.Value); %get right inner limit
            disp('Set inner positions')
        end

        % Button pushed function: SpoutsetouterButton
        function SpoutsetouterButtonPushed(app, event)
            global BpodSystem
            BpodSystem.ProtocolSettings.lOuterLim = ceil(app.SpoutLeftKnob.Value); %get left inner limit
            BpodSystem.ProtocolSettings.rOuterLim = ceil(app.SpoutRightKnob.Value); %get right inner limit
            disp('Set outer positions')
        end

        % Value changed function: KnobmaxEditField
        function KnobmaxEditFieldValueChanged(app, event)
            value = app.KnobmaxEditField.Value;
            set(app.SpoutLeftKnob,'Limits',[0,value]);
            set(app.SpoutRightKnob,'Limits',[0,value]);
        end

        % Callback function
        function spoutspeedEditFieldValueChanged(app, event)
            value = app.spoutspeedEditField.Value;
            global BpodSystem
            app.sendTeensyStepperTrialInfo();
            BpodSystem.ProtocolSettings.SpoutSpeed = value; %update SpoutSpeed
            teensyWrite([73 length(num2str(value)) num2str(value)]); %set spout speed
        end

        % Value changed function: SpoutLeftKnob
        function SpoutLeftKnobValueChanged(app, event)
            value = app.SpoutLeftKnob.Value;
            set(app.LeftSpoutEdit,'Value',value);
            if app.SpoutMirrorspouts.Value
                set(app.SpoutRightKnob,'Value',value);
            end
            app.moveSpouts(app.SpoutLeftKnob.Value,app.SpoutRightKnob.Value);
        end

        % Value changing function: SpoutLeftKnob
        function SpoutLeftKnobValueChanging(app, event)
            valuel = ceil(event.Value);
            valuer = ceil(event.Value);
            set(app.LeftSpoutEdit,'Value',valuel);
            if app.SpoutMirrorspouts.Value == false
                valuer = app.SpoutRightKnob.Value;
            end
            set(app.RightSpoutEdit,'Value',valuer);
            app.moveSpouts(valuel,valuer);
        end

        % Value changing function: SpoutRightKnob
        function SpoutRightKnobValueChanging(app, event)
            valuel = ceil(event.Value);
            valuer = ceil(event.Value);
            set(app.RightSpoutEdit,'Value',valuer);
            if app.SpoutMirrorspouts.Value == false
                valuel = app.SpoutLeftKnob.Value;
            end
            set(app.LeftSpoutEdit,'Value',valuel);
            app.moveSpouts(valuel,valuer);
        end

        % Callback function
        function HandlepositionKnobValueChanging(app, event)
            changingValue = ceil(event.Value);
            app.moveHandles(changingValue);
        end

        % Callback function
        function HandlezeroButtonPushed(app, event)
            app.moveHandles(0);
        end

        % Callback function
        function handletoouterButtonPushed(app, event)
            global BpodSystem
            app.moveHandles(BpodSystem.ProtocolSettings.LeverOut);
            
        end

        % Callback function
        function handletoinnerButtonPushed(app, event)
            global BpodSystem
            app.moveHandles(BpodSystem.ProtocolSettings.LeverIn);
        end

        % Callback function
        function handlesetinnerButtonPushed(app, event)
            global BpodSystem
            %             BpodSystem.ProtocolSettings.LeverIn = ceil(app.HandlepositionKnob.Value);
        end

        % Callback function
        function handlesetouterButtonPushed(app, event)
            global BpodSystem
            %             BpodSystem.ProtocolSettings.LeverOut = ceil(app.HandlepositionKnob.Value);
        end

        % Callback function
        function handlespeedValueChanged(app, event)
            %             value = app.handlespeed.Value;
            %             BpodSystem.ProtocolSettings.LeverSpeed = value; %update LeverSpeed
            %             app.sendTeensyStepperTrialInfo();
            %             s = num2str(ceil(value));
            %             teensyWrite([74 length(s) s]); %set lever speed
            
            
        end

        % Callback function
        function TouchthresholdsEditFieldValueChanged(app, event)
            value = ceil(app.TouchthresholdsEditField.Value);
            if value < 1
                value = 1;
                set(app.TouchthresholdsEditField,'Value',1);
            end
            global BpodSystem
            BpodSystem.ProtocolSettings.TouchThresh = value;
            cVal = num2str(value);
            teensyWrite([75 length(cVal) cVal]);
            
            %update spout sliders
            cVal = str2double(cVal);
            if app.LeftspoutSlider.Limits(2) < cVal
                app.LeftspoutSlider.Limits(2) = cVal;
            end
            if app.RightspoutSlider.Limits(2) < cVal
                app.RightspoutSlider.Limits(2) = cVal;
            end
            app.LeftspoutSlider.Value = cVal;
            app.RightspoutSlider.Value = cVal;
        end

        % Button pushed function: UpdateThreshButton
        function UpdateThreshButtonPushed(app, event)
            global BpodSystem
            value = BpodSystem.ProtocolSettings.TouchThresh;
            cVal = num2str(value);
            teensyWrite([75 length(cVal) cVal]);
            
            %update spout sliders
            cVal = str2double(cVal);
            if app.LeftspoutSlider.Limits(2) < cVal
                app.LeftspoutSlider.Limits(2) = cVal;
            end
            if app.RightspoutSlider.Limits(2) < cVal
                app.RightspoutSlider.Limits(2) = cVal;
            end
            app.LeftspoutSlider.Value = cVal;
            app.RightspoutSlider.Value = cVal;
            
            % get current thresholds
            pause(3);
            res = teensyGetTouchThresh;
            ii = 0;
            while sum(res) == 0
                res = teensyGetTouchThresh();
                ii = ii+1;
                if ii == 5
                    disp('There was an error reading the thresholds from the teensy...')
                    break
                end
            end
            BpodSystem.ProtocolSettings.capacitiveTouchThresholds = res; disp(res);
        end

        % Value changed function: LeftspoutSlider
        function LeftspoutSliderValueChanged(app, event)
            % set(app.LeftspoutSlider,'Value',0);
            % bring back to zero
        end

        % Value changing function: LeftspoutSlider
        function LeftspoutSliderValueChanging(app, event)
            global BpodSystem
            if event.Value > 0
                cSTD = BpodSystem.ProtocolSettings.capacitiveTouchThresholds; %get standard deviations for left and right
                res = teensyGetTouchThresh; % get current standard deviations from teensy
                if sum(res) > 0
                    res(1) = cSTD(1) * event.Value; %update left side with slider value
                    teensySetTouchThresh(res);
                end
            end
        end

        % Value changing function: RightspoutSlider
        function RightspoutSliderValueChanging(app, event)
            global BpodSystem
            if event.Value > 0
                cSTD = BpodSystem.ProtocolSettings.capacitiveTouchThresholds; %get standard deviations for left and right
                res = teensyGetTouchThresh; % get current standard deviations from teensy
                if sum(res) > 0
                    res(2) = cSTD(2) * event.Value; %update right side with slider value
                    teensySetTouchThresh(res);
                end
            end
            
        end

        % Value changed function: RightspoutSlider
        function RightspoutSliderValueChanged(app, event)
            %             value = app.RightspoutSlider.Value;
            %             set(app.RightspoutSlider,'Value',0);
            
        end

        % Callback function
        function RighthandleSliderValueChanged(app, event)
            value = app.RighthandleSlider.Value;
            set(app.RighthandleSlider,'Value',0);
        end

        % Callback function
        function LefthandleSliderValueChanged(app, event)
            value = app.LefthandleSlider.Value;
            set(app.LefthandleSlider,'Value',0);
            
        end

        % Callback function
        function LefthandleSliderValueChanging(app, event)
            changingValue = event.Value;
            if changingValue > 0
                for i = 1:abs(changingValue)
                    teensyWrite(84);
                end
            else
                for i = 1:abs(changingValue)
                    teensyWrite(85);
                end
            end
        end

        % Value changed function: AutorewardCheckBox
        function AutorewardCheckBoxValueChanged(app, event)
            value = app.AutorewardCheckBox.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.AutoReward = value;
        end

        % Callback function
        function fractionmixedEditFieldValueChanged(app, event)
            value = app.fractionmixedEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.fractionTrainingMixed = value;
        end

        % Callback function
        function fractionvisualEditFieldValueChanged(app, event)
            value = app.fractionvisualEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.fractionTrainingVision = value;
        end

        % Callback function
        function RighthandleSliderValueChanging(app, event)
            changingValue = event.Value;
            for i = 1:abs(changingValue)
                if changingValue > 0
                    teensyWrite(86);
                else
                    teensyWrite(87);
                end
            end
        end

        % Callback function
        function fractionaudioEditFieldValueChanged(app, event)
            value = app.fractionaudioEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.fractionTrainingAudio = value;
        end

        % Callback function
        function fractiontactileEditFieldValueChanged(app, event)
            value = app.fractiontactileEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.fractionTrainingTactile = value;
        end

        % Value changed function: ModalityDropDown
        function ModalityDropDownValueChanged(app, event)
            value = app.ModalityDropDown.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.RewardedModality = value;
        end

        % Callback function
        function pRightEditFieldValueChanged(app, event)
            value = app.pRightEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.ProbRight = value;
        end

        % Callback function
        function TargetrateEditFieldValueChanged(app, event)
            value = app.TargetrateEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.StimRate = value;
        end

        % Callback function
        function pDistEditFieldValueChanged(app, event)
            value = app.pDistEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.DistProb = value;
        end

        % Callback function
        function pAudioEditFieldValueChanged(app, event)
            value = app.pAudioEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.ProbAudio = value;
        end

        % Callback function
        function pVisualEditFieldValueChanged(app, event)
            value = app.pVisualEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.ProbVision = value;
        end

        % Callback function
        function pSomatosensoryEditFieldValueChanged(app, event)
            value = app.pSomatosensoryEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.ProbTactile = value;
        end

        % Value changed function: distractorEditField
        function distractorEditFieldValueChanged(app, event)
            value = app.distractorEditField.Value;
            p = str2num(value);
            global BpodSystem
            BpodSystem.ProtocolSettings.DistFractions = p;
        end

        % Callback function
        function SavesettingsButtonPushed(app, event)
            global BpodSystem
            ProtocolSettings = BpodSystem.ProtocolSettings;
            fname = BpodSystem.GUIData.SettingsFileName;
            save(fname, 'ProtocolSettings');
            fprintf('Saving to %s\n',fname)
        end

        % Callback function
        function MovehandlesCheckBoxValueChanged(app, event)
            %             value = app.MovehandlesCheckBox.Value;
            %             global BpodSystem
            %             BpodSystem.ProtocolSettings.MoveLever = value;
        end

        % Callback function
        function HandlewaittimeSpinnerValueChanged(app, event)
            value = app.HandlewaittimeSpinner.Value;
            global BpodSystem
            %             BpodSystem.ProtocolSettings.LeverWait = value;
        end

        % Value changed function: StimulidurationSpinner
        function StimulidurationSpinnerValueChanged(app, event)
            value = app.StimulidurationSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.StimDuration = value;
        end

        % Value changed function: spoutspeed
        function spoutspeedValueChanged(app, event)
            value = app.spoutspeed.Value;
            global BpodSystem
            app.sendTeensyStepperTrialInfo();
            BpodSystem.ProtocolSettings.SpoutSpeed = value; %update SpoutSpeed
            teensyWrite([73 length(num2str(value)) num2str(value)]); %set spout speed
        end

        % Callback function
        function handle_speedValueChanged(app, event)
            %             value = app.handle_speed.Value;
            %             global BpodSystem
            %             BpodSystem.ProtocolSettings.LeverSpeed = value; %update LeverSpeed
            %             app.sendTeensyStepperTrialInfo();
            %             s = num2str(ceil(value));
            %             teensyWrite([74 length(s) s]); %set lever speed
        end

        % Value changed function: TouchthresholdsdSpinner
        function TouchthresholdsdSpinnerValueChanged(app, event)
            value = ceil(app.TouchthresholdsdSpinner.Value);
            global BpodSystem
            BpodSystem.ProtocolSettings.TouchThresh = value;
            cVal = num2str(value);
            teensyWrite([75 length(cVal) cVal]);
            
            %update spout sliders
            cVal = str2double(cVal);
            if app.LeftspoutSlider.Limits(2) < cVal
                app.LeftspoutSlider.Limits(2) = cVal;
            end
            if app.RightspoutSlider.Limits(2) < cVal
                app.RightspoutSlider.Limits(2) = cVal;
            end
            app.LeftspoutSlider.Value = cVal;
            app.RightspoutSlider.Value = cVal;
            
            % get touch threshold
            pause(3);
            res = teensyGetTouchThresh();
            ii = 0;
            while sum(res) == 0
                res = teensyGetTouchThresh();
                ii = ii+1;
                if ii == 5
                    disp('There was an error reading the thresholds from the teensy...')
                    break
                end
            end
            BpodSystem.ProtocolSettings.capacitiveTouchThresholds = res; disp(res);
        end

        % Value changed function: ITI_scale
        function ITI_scaleValueChanged(app, event)
            value = app.ITI_scale.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.ITI_scale = value;
        end

        % Value changed function: TimetoconfirmSpinner
        function TimetoconfirmSpinnerValueChanged(app, event)
            value = app.TimetoconfirmSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.TimeToConfirm = value;
            
        end

        % Value changed function: HappytimeSpinner
        function HappytimeSpinnerValueChanged(app, event)
            value = app.HappytimeSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.happyTime = value;
        end

        % Value changed function: TimeoutSpinner
        function TimeoutSpinnerValueChanged(app, event)
            value = app.TimeoutSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.TimeOut = value;
        end

        % Value changed function: rewardSizeSpinner
        function rewardSizeSpinnerValueChanged(app, event)
            value = app.rewardSizeSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.leftRewardVolume = value;
            BpodSystem.ProtocolSettings.rightRewardVolume = value;
        end

        % Value changed function: PunishdurationSpinner
        function PunishdurationSpinnerValueChanged(app, event)
            value = app.PunishdurationSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.PunishSoundDur = value;
        end

        % Value changed function: pAudioSpinner
        function pAudioSpinnerValueChanged(app, event)
            value = app.pAudioSpinner.Value;
            global BpodSystem
            value = min([value (1 - (BpodSystem.ProtocolSettings.ProbVision + BpodSystem.ProtocolSettings.ProbTactile))]);
            BpodSystem.ProtocolSettings.ProbAudio = value;
            app.pAudioSpinner.Value = value;
        end

        % Value changed function: pVisualSpinner
        function pVisualSpinnerValueChanged(app, event)
            value = app.pVisualSpinner.Value;
            global BpodSystem
            value = min([value (1 - (BpodSystem.ProtocolSettings.ProbAudio + BpodSystem.ProtocolSettings.ProbTactile))]);
            BpodSystem.ProtocolSettings.ProbVision = value;
            app.pVisualSpinner.Value = value;
        end

        % Value changed function: pSomatosensorySpinner
        function pSomatosensorySpinnerValueChanged(app, event)
            value = app.pSomatosensorySpinner.Value;
            global BpodSystem
            value = min([value (1 - (BpodSystem.ProtocolSettings.ProbAudio + BpodSystem.ProtocolSettings.ProbVision))]);
            BpodSystem.ProtocolSettings.ProbTactile = value;
            app.pSomatosensorySpinner.Value = value;
        end

        % Value changed function: pDistSpinner
        function pDistSpinnerValueChanged(app, event)
            value = app.pDistSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.DistProb = value;
        end

        % Value changed function: pRightSpinner
        function pRightSpinnerValueChanged(app, event)
            value = app.pRightSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.ProbRight = value;
        end

        % Value changed function: StimrateSpinner
        function StimrateSpinnerValueChanged(app, event)
            value = app.StimrateSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.StimRate = value;
        end

        % Value changed function: fractionmixedSpinner
        function fractionmixedSpinnerValueChanged(app, event)
            value = app.fractionmixedSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.fractionTrainingMixed = value;
        end

        % Value changed function: fractionaudioSpinner
        function fractionaudioSpinnerValueChanged(app, event)
            value = app.fractionaudioSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.fractionTrainingAudio = value;
        end

        % Value changed function: fractionvisionSpinner
        function fractionvisionSpinnerValueChanged(app, event)
            value = app.fractionvisionSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.fractionTrainingVision = value;
        end

        % Value changed function: fractiontactileSpinner
        function fractiontactileSpinnerValueChanged(app, event)
            value = app.fractiontactileSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.fractionTrainingTactile = value;
        end

        % Value changed function: WeightgSpinner
        function WeightgSpinnerValueChanged(app, event)
            value = app.WeightgSpinner.Value;
            global BpodSystem
            BpodSystem.Data.animalWeight  = value;
            set(app.WeightgSpinnerLabel,'FontColor','k');
        end

        % Callback function
        function RegularstimuliCheckBoxValueChanged(app, event)
            value = app.RegularstimuliCheckBox.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.RegularStim  = value;
        end

        % Value changed function: SavesettingsCheckBox
        function SavesettingsCheckBoxValueChanged(app, event)
            value = app.SavesettingsCheckBox.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.SaveSettings = value;
            if value
                ProtocolSettings = BpodSystem.ProtocolSettings;
                fname = BpodSystem.GUIData.SettingsFileName;
                save(fname, 'ProtocolSettings');
                fprintf('Saving to %s\n',fname);
            end
        end

        % Value changed function: FlashdurmsSpinner
        function FlashdurmsSpinnerValueChanged(app, event)
            value = app.FlashdurmsSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.FlashDuration = value;
        end

        % Value changed function: PuffdurmsSpinner
        function PuffdurmsSpinnerValueChanged(app, event)
            value = app.PuffdurmsSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.BuzzDuration = value;
        end

        % Value changed function: BeepdurmsSpinner
        function BeepdurmsSpinnerValueChanged(app, event)
            value = app.BeepdurmsSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.BeepDuration = value;
        end

        % Value changed function: brightnessSpinner
        function brightnessSpinnerValueChanged(app, event)
            value = app.brightnessSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.StimBrightness = value;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global BpodSystem
            
            if strcmp(get(app.StopButton,'text'),'Stop')
                BpodSystem.Status.PuffyPenguinExit = true;
                %stop(app.bpodtimer)
            else
                if ~BpodSystem.Status.BeingUsed
                    set(app.StopButton,'text','Stop')
                    NewLaunchManager;
                end
            end
            
        end

        % Value changed function: loudnessSpinner
        function loudnessSpinnerValueChanged(app, event)
            value = app.loudnessSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.StimLoudness = value;
        end

        % Value changed function: puffnessSpinner
        function puffnessSpinnerValueChanged(app, event)
            value = app.puffnessSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.BuzzStrength = value;
        end

        % Button pushed function: GiveRightWater
        function GiveRightWaterPushed(app, event)
            app.giveWater('right');
        end

        % Button pushed function: GiveLeftWater
        function GiveLeftWaterPushed(app, event)
            app.giveWater('left');
        end

        % Button pushed function: GiveBothWater
        function GiveBothWaterPushed(app, event)
            app.giveWater('both');
        end

        % Callback function
        function widefieldCheckBoxValueChanged(app, event)
            value = app.widefieldCheckBox.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.triggerWidefield = value;
        end

        % Value changed function: widefieldaddressEditField
        function widefieldaddressEditFieldValueChanged(app, event)
            value = app.widefieldaddressEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.labcamsWidefield = value;
        end

        % Value changed function: ITI_shape
        function ITI_shapeValueChanged(app, event)
            value = app.ITI_shape.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.ITI_shape = value;
        end

        % Value changed function: TimetochooseSpinner
        function TimetochooseSpinnerValueChanged(app, event)
            value = app.TimetochooseSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.TimeToChoose = value;
        end

        % Button pushed function: readTouchThresholds
        function readTouchThresholdsPushed(app, event)
            res = teensyGetTouchThresh();
            ii = 0;
            while sum(res) == 0
                res = teensyGetTouchThresh();
                ii = ii+1;
                if ii == 5
                    disp('There was an error reading the thresholds from the teensy...')
                    break
                end
            end
            global BpodSystem
            BpodSystem.ProtocolSettings.capacitiveTouchThresholds = res;
        end

        % Value changed function: ServerpathEditField
        function ServerpathEditFieldValueChanged(app, event)
            value = app.ServerpathEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.serverPath = value;
        end

        % Value changed function: AudiodistractorCheckBox
        function AudiodistractorCheckBoxValueChanged(app, event)
            value = app.AudiodistractorCheckBox.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.useDistAudio = value;
        end

        % Value changed function: VisualdistractorCheckBox
        function VisualdistractorCheckBoxValueChanged(app, event)
            value = app.VisualdistractorCheckBox.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.useDistVisual = value;
        end

        % Value changed function: SomatossensorydistractorCheckBox
        function SomatossensorydistractorCheckBoxValueChanged(app, event)
            value = app.SomatossensorydistractorCheckBox.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.useDistTactile = value;
        end

        % Callback function
        function TouchsoundCheckBoxValueChanged(app, event)
            %             value = app.TouchsoundCheckBox.Value;
            %             global BpodSystem
            %             BpodSystem.ProtocolSettings.LeverSound = value;
        end

        % Value changed function: TrialstartsoundCheckBox
        function TrialstartsoundCheckBoxValueChanged(app, event)
            value = app.TrialstartsoundCheckBox.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.TrialStartCue = value;
        end

        % Callback function
        function TimeoutSpinner_2ValueChanged(app, event)
            value = app.TimeoutSpinner_2.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.TimeOut = value;
        end

        % Value changing function: PunishdurationSpinner
        function PunishdurationSpinnerValueChanging(app, event)
            changingValue = event.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.PunishSoundDur = value;
            
        end

        % Value changed function: prestimdelay
        function prestimdelayValueChanged(app, event)
            value = app.prestimdelay.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.preStimDelay = value;
        end

        % Callback function
        function HandlewaittimeSpinner_3ValueChanged(app, event)
            value = app.HandlewaittimeSpinner_3.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.handleHoldDelay = value;
        end

        % Value changed function: StimCueCheckBox
        function StimCueCheckBoxValueChanged(app, event)
            value = app.StimCueCheckBox.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.UseStimStartCue = value;
        end

        % Callback function
        function DecisionDelaySpinnerValueChanged(app, event)
            value = app.DecisionDelaySpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.DecisionGap = value;
        end

        % Value changed function: Delay2valuesforminmaxEditField
        function Delay2valuesforminmaxEditFieldValueChanged(app, event)
            value = app.Delay2valuesforminmaxEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.DecisionGap = str2num(value);
        end

        % Value changed function: MaxtrialsonsamesideSpinner
        function MaxtrialsonsamesideSpinnerValueChanged(app, event)
            value = app.MaxtrialsonsamesideSpinner.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.biasSeqLength = value;
        end

        % Value changed function: labcamsaddressEditField
        function labcamsaddressEditFieldValueChanged(app, event)
            value = app.labcamsaddressEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.labcamsAddress = value;
        end

        % Callback function
        function scanboxCheckboxValueChanged(app, event)
            value = app.scanboxCheckbox.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.triggerScanbox = value;
        end

        % Value changed function: optoperiodDropDown
        function optoperiodDropDownValueChanged(app, event)
            value = app.optoperiodDropDown.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.optoPeriod = value;
        end

        % Value changed function: optoamp1EditField
        function optoamp1EditFieldValueChanged(app, event)
            value = app.optoamp1EditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.optoAmp1 = value;
        end

        % Value changed function: optofractiontrialsEditField
        function optofractiontrialsEditFieldValueChanged(app, event)
            value = app.optofractiontrialsEditField.Value;
            if value > 1; value = 1; app.optofractiontrialsEditField.Value = value; end %make sure this is not larger as 1
            global BpodSystem
            BpodSystem.ProtocolSettings.optoProb = value;
        end

        % Value changed function: LeftSpoutEdit
        function LeftSpoutEditValueChanged(app, event)
            value = app.LeftSpoutEdit.Value;
            if value ~= app.SpoutLeftKnob.Value
                set(app.SpoutLeftKnob,'Value',value);
            end; drawnow;
            app.moveSpouts(app.SpoutLeftKnob.Value,app.SpoutRightKnob.Value);
        end

        % Value changed function: RightSpoutEdit
        function RightSpoutEditValueChanged(app, event)
            value = app.RightSpoutEdit.Value;
            if value ~= app.SpoutRightKnob.Value
                set(app.SpoutRightKnob,'Value',value);
            end; drawnow;
            app.moveSpouts(app.SpoutLeftKnob.Value,app.SpoutRightKnob.Value);
        end

        % Value changed function: SpoutRightKnob
        function SpoutRightKnobValueChanged(app, event)
            value = app.SpoutRightKnob.Value;
            set(app.RightSpoutEdit,'Value',value);
            if app.SpoutMirrorspouts.Value
                set(app.SpoutLeftKnob,'Value',value);
            end
            app.moveSpouts(app.SpoutLeftKnob.Value,app.SpoutRightKnob.Value);
        end

        % Value changed function: targetEditField
        function targetEditFieldValueChanged(app, event)
            global BpodSystem
            value = app.targetEditField.Value;
            BpodSystem.ProtocolSettings.TargFractions = str2num(value);
        end

        % Value changed function: RightspoutbiasSpinner
        function RightspoutbiasSpinnerValueChanged(app, event)
            value = app.RightspoutbiasSpinner.Value;
            global BpodSystem
            %             if value >= 0
            BpodSystem.ProtocolSettings.ServoPos(2) = value;
            %             else
            %                 app.RightspoutbiasSpinner.Value = 0;
            %             end
        end

        % Value changed function: LeftspoutbiasSpinner
        function LeftspoutbiasSpinnerValueChanged(app, event)
            value = app.LeftspoutbiasSpinner.Value;
            global BpodSystem
            %             if value >= 0
            BpodSystem.ProtocolSettings.ServoPos(1) = value;
            %             else
            %                 app.LeftspoutbiasSpinner.Value = 0;
            %             end
        end

        % Value changed function: optoamp2EditField
        function optoamp2EditFieldValueChanged(app, event)
            value = app.optoamp2EditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.optoAmp2 = value;
        end

        % Value changed function: optoRampEditField
        function optoRampEditFieldValueChanged(app, event)
            value = app.optoRampEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.optoRamp = value;
        end

        % Value changed function: UseblackscreenCheckBox
        function UseblackscreenCheckBoxValueChanged(app, event)
            value = app.UseblackscreenCheckBox.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.blackScreen = value;
        end

        % Value changed function: StimulusscreensEditField
        function StimulusscreensEditFieldValueChanged(app, event)
            value = str2num(app.StimulusscreensEditField.Value);
            global BpodSystem
            BpodSystem.ProtocolSettings.stimScreens = value;
            app.StimulusscreensEditField.Value = num2str(value); %this makes sure that text can be translated into numbers and puts a null otherwise
        end

        % Value changed function: optodurationEditField
        function optodurationEditFieldValueChanged(app, event)
            value = app.optodurationEditField.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.optoDur = value;
        end

        % Value changed function: optoLocation
        function optoLocationValueChanged(app, event)
            value = app.optoLocation.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.optoLocation = value;
        end

        % Value changed function: ShowWaterCheckBox
        function ShowWaterCheckBoxValueChanged(app, event)
            value = app.ShowWaterCheckBox.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.showWater = value;
        end

        % Value changed function: optoProbRight
        function optoProbRightValueChanged(app, event)
            value = app.optoProbRight.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.optoRight = value;
        end

        % Value changed function: optoProbBoth
        function optoProbBothValueChanged(app, event)
            value = app.optoProbBoth.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.optoBoth = value;
        end

        % Value changed function: optoSeqActive
        function optoSeqActiveValueChanged(app, event)
            value = app.optoSeqActive.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.optoSeqActive = value;
            
            if value
                app.optofractiontrialsEditField.Enable = "off"; %no opto probabilities in sequence mode
                %                 app.optoSeqInterval.Enable = "on";
                %                 app.optoSeqTrials.Enable = "on";
            else
                app.optofractiontrialsEditField.Enable = "on"; %active opto probabilities again
                %                 app.optoSeqInterval.Enable = "off";
                %                 app.optoSeqTrials.Enable = "off";
            end
        end

        % Value changed function: optoSeqTrials
        function optoSeqTrialsValueChanged(app, event)
            value = app.optoSeqTrials.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.optoSeqTrials = value;
        end

        % Value changed function: optoSeqInterval
        function optoSeqIntervalValueChanged(app, event)
            value = app.optoSeqInterval.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.optoSeqInterval = value;
        end

        % Callback function
        function optoSeqUnitsValueChanged(app, event)
            
        end

        % Value changed function: optoSeqStartTime
        function optoSeqStartTimeValueChanged(app, event)
            value = app.optoSeqStartTime.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.optoSeqStartTime = value;
        end

        % Value changed function: optoSeqUnilateral
        function optoSeqUnilateralValueChanged(app, event)
            value = app.optoSeqUnilateral.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.optoSeqUnilateral = value;
        end

        % Value changed function: spoutOffset
        function spoutOffsetValueChanged(app, event)
            value = app.spoutOffset.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.spoutOffset = value;
        end

        % Value changed function: distDifficulties
        function distDifficultiesValueChanged(app, event)
            global BpodSystem
            value = round(str2num(app.distDifficulties.Value));
            if ~isempty(value)
                % limit difficulties between 1 and 5 to remain in
                % reasonable discrimination range
                value(value < 1) = [];
                value(value >= BpodSystem.ProtocolSettings.StimRate) = [];
                BpodSystem.ProtocolSettings.distDifficulties = value;
                app.distDifficulties.Value = num2str(sort(value));
            else
                app.distDifficulties.Value = '1';
                BpodSystem.ProtocolSettings.distDifficulties = 1;
            end
        end

        % Value changed function: maxDetectionSequence
        function maxDetectionSequenceValueChanged(app, event)
            value = app.maxDetectionSequence.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.maxDetectionSequence = value;
        end

        % Value changed function: contingencyReversal
        function contingencyReversalValueChanged(app, event)
            value = app.contingencyReversal.Value;
            global BpodSystem
            BpodSystem.ProtocolSettings.contingencyReversal = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PuffyPenguinUIFigure = uifigure('Visible', 'off');
            app.PuffyPenguinUIFigure.Name = 'PuffyPenguin';
            app.TabGroup = uitabgroup(app.PuffyPenguinUIFigure);
            app.AdjustmentTab = uitab(app.TabGroup);
            app.AdjustmentTab.Title = 'Adjustment';
            app.SpoutadjustmentPanel = uipanel(app.AdjustmentTab);
            app.SpoutadjustmentPanel.Title = 'Spout adjustment';
            app.SpoutsetinnerButton = uibutton(app.SpoutadjustmentPanel, 'push');
            app.SpoutsetinnerButton.ButtonPushedFcn = createCallbackFcn(app, @SpoutsetinnerButtonPushed, true);
            app.SpoutsetinnerButton.Text = 'set';
            app.SpoutsetouterButton = uibutton(app.SpoutadjustmentPanel, 'push');
            app.SpoutsetouterButton.ButtonPushedFcn = createCallbackFcn(app, @SpoutsetouterButtonPushed, true);
            app.SpoutsetouterButton.Text = 'set';
            app.SpoutMirrorspouts = uicheckbox(app.SpoutadjustmentPanel);
            app.SpoutMirrorspouts.Text = 'Mirror movement';
            app.SpoutMirrorspouts.Value = true;
            app.LeftspoutKnobLabel = uilabel(app.SpoutadjustmentPanel);
            app.LeftspoutKnobLabel.Text = 'Left spout';
            app.SpoutLeftKnob = uiknob(app.SpoutadjustmentPanel, 'continuous');
            app.SpoutLeftKnob.Limits = [0 150];
            app.SpoutLeftKnob.ValueChangedFcn = createCallbackFcn(app, @SpoutLeftKnobValueChanged, true);
            app.SpoutLeftKnob.ValueChangingFcn = createCallbackFcn(app, @SpoutLeftKnobValueChanging, true);
            app.RightspoutKnobLabel = uilabel(app.SpoutadjustmentPanel);
            app.RightspoutKnobLabel.Text = 'Right spout';
            app.SpoutRightKnob = uiknob(app.SpoutadjustmentPanel, 'continuous');
            app.SpoutRightKnob.Limits = [0 150];
            app.SpoutRightKnob.ValueChangedFcn = createCallbackFcn(app, @SpoutRightKnobValueChanged, true);
            app.SpoutRightKnob.ValueChangingFcn = createCallbackFcn(app, @SpoutRightKnobValueChanging, true);
            app.KnobmaxEditFieldLabel = uilabel(app.SpoutadjustmentPanel);
            app.KnobmaxEditFieldLabel.Text = 'Knob max';
            app.KnobmaxEditField = uieditfield(app.SpoutadjustmentPanel, 'numeric');
            app.KnobmaxEditField.ValueChangedFcn = createCallbackFcn(app, @KnobmaxEditFieldValueChanged, true);
            app.KnobmaxEditField.Value = 150;
            app.spouttozero = uibutton(app.SpoutadjustmentPanel, 'push');
            app.spouttozero.ButtonPushedFcn = createCallbackFcn(app, @spouttozeroButtonPushed, true);
            app.spouttozero.Text = 'zero';
            app.spouttoouter = uibutton(app.SpoutadjustmentPanel, 'push');
            app.spouttoouter.ButtonPushedFcn = createCallbackFcn(app, @spouttoouterButtonPushed, true);
            app.spouttoouter.Text = 'outer';
            app.spouttoinner = uibutton(app.SpoutadjustmentPanel, 'push');
            app.spouttoinner.ButtonPushedFcn = createCallbackFcn(app, @spouttoinnerButtonPushed, true);
            app.spouttoinner.Text = 'inner';
            app.MovementdurationmsSpinnerLabel = uilabel(app.SpoutadjustmentPanel);
            app.MovementdurationmsSpinnerLabel.Text = 'Movement duration (ms)';
            app.spoutspeed = uispinner(app.SpoutadjustmentPanel);
            app.spoutspeed.Step = 5;
            app.spoutspeed.Limits = [0 5000];
            app.spoutspeed.ValueChangedFcn = createCallbackFcn(app, @spoutspeedValueChanged, true);
            app.spoutspeed.Value = 25;
            app.GiveRightWater = uibutton(app.SpoutadjustmentPanel, 'push');
            app.GiveRightWater.ButtonPushedFcn = createCallbackFcn(app, @GiveRightWaterPushed, true);
            app.GiveRightWater.Text = 'Give water';
            app.GiveLeftWater = uibutton(app.SpoutadjustmentPanel, 'push');
            app.GiveLeftWater.ButtonPushedFcn = createCallbackFcn(app, @GiveLeftWaterPushed, true);
            app.GiveLeftWater.Text = 'Give water';
            app.GiveBothWater = uibutton(app.SpoutadjustmentPanel, 'push');
            app.GiveBothWater.ButtonPushedFcn = createCallbackFcn(app, @GiveBothWaterPushed, true);
            app.GiveBothWater.Text = 'Give water';
            app.WaterDispenserSpinner = uispinner(app.SpoutadjustmentPanel);
            app.WaterDispenserSpinner.Limits = [1 100];
            app.WaterDispenserSpinner.Value = 2;
            app.LeftEditFieldLabel = uilabel(app.SpoutadjustmentPanel);
            app.LeftEditFieldLabel.Text = 'Left';
            app.LeftSpoutEdit = uieditfield(app.SpoutadjustmentPanel, 'numeric');
            app.LeftSpoutEdit.ValueChangedFcn = createCallbackFcn(app, @LeftSpoutEditValueChanged, true);
            app.RightEditFieldLabel = uilabel(app.SpoutadjustmentPanel);
            app.RightEditFieldLabel.Text = 'Right';
            app.RightSpoutEdit = uieditfield(app.SpoutadjustmentPanel, 'numeric');
            app.RightSpoutEdit.ValueChangedFcn = createCallbackFcn(app, @RightSpoutEditValueChanged, true);
            app.OffsetLabel = uilabel(app.SpoutadjustmentPanel);
            app.OffsetLabel.Text = 'spoutOffset';
            app.spoutOffset = uieditfield(app.SpoutadjustmentPanel, 'numeric');
            app.spoutOffset.Limits = [1 50];
            app.spoutOffset.ValueChangedFcn = createCallbackFcn(app, @spoutOffsetValueChanged, true);
            app.spoutOffset.Value = 20;
            app.SensorthresholdsPanel = uipanel(app.AdjustmentTab);
            app.SensorthresholdsPanel.Title = 'Sensor thresholds';
            app.LeftspoutSliderLabel = uilabel(app.SensorthresholdsPanel);
            app.LeftspoutSliderLabel.Text = 'Left spout';
            app.LeftspoutSlider = uislider(app.SensorthresholdsPanel);
            app.LeftspoutSlider.Limits = [0 6];
            app.LeftspoutSlider.ValueChangedFcn = createCallbackFcn(app, @LeftspoutSliderValueChanged, true);
            app.LeftspoutSlider.ValueChangingFcn = createCallbackFcn(app, @LeftspoutSliderValueChanging, true);
            app.LeftspoutSlider.Value = 3;
            app.RightspoutSliderLabel = uilabel(app.SensorthresholdsPanel);
            app.RightspoutSliderLabel.Text = 'Right spout';
            app.RightspoutSlider = uislider(app.SensorthresholdsPanel);
            app.RightspoutSlider.Limits = [0 6];
            app.RightspoutSlider.ValueChangedFcn = createCallbackFcn(app, @RightspoutSliderValueChanged, true);
            app.RightspoutSlider.ValueChangingFcn = createCallbackFcn(app, @RightspoutSliderValueChanging, true);
            app.RightspoutSlider.Value = 3;
            app.UpdateThreshButton = uibutton(app.SensorthresholdsPanel, 'push');
            app.UpdateThreshButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateThreshButtonPushed, true);
            app.UpdateThreshButton.Text = 'Update';
            app.TouchthresholdsdSpinnerLabel = uilabel(app.SensorthresholdsPanel);
            app.TouchthresholdsdSpinnerLabel.Text = 'Touch threshold (sd)';
            app.TouchthresholdsdSpinner = uispinner(app.SensorthresholdsPanel);
            app.TouchthresholdsdSpinner.Limits = [1 100];
            app.TouchthresholdsdSpinner.ValueChangedFcn = createCallbackFcn(app, @TouchthresholdsdSpinnerValueChanged, true);
            app.TouchthresholdsdSpinner.Value = 3;
            app.readTouchThresholds = uibutton(app.SensorthresholdsPanel, 'push');
            app.readTouchThresholds.ButtonPushedFcn = createCallbackFcn(app, @readTouchThresholdsPushed, true);
            app.readTouchThresholds.Text = 'Read from teensy';
            app.TaskTab = uitab(app.TabGroup);
            app.TaskTab.Title = 'Task';
            app.trial_performance_ax = uiaxes(app.TaskTab);
            xlabel(app.trial_performance_ax, 'trial number')
            app.trial_ax = uiaxes(app.TaskTab);
            app.trial_ax.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.trial_ax.XTickLabel = '';
            app.trial_ax.YTick = [0 1];
            app.trial_ax.YTickLabel = {'L'; 'R'};
            app.stimuli_ax = uiaxes(app.TaskTab);
            title(app.stimuli_ax, 'Stimuli')
            zlabel(app.stimuli_ax, 'Z')
            app.psychometrics_ax = uiaxes(app.TaskTab);
            title(app.psychometrics_ax, 'Psychometrics')
            ylabel(app.psychometrics_ax, 'Fraction correct')
            zlabel(app.psychometrics_ax, 'Z')
            app.psychometrics_ax.XTick = [];
            app.psychometrics_ax.YTick = [];
            app.TasksettingsPanel = uipanel(app.TaskTab);
            app.TasksettingsPanel.Title = 'Task settings';
            app.distractorEditFieldLabel = uilabel(app.TasksettingsPanel);
            app.distractorEditFieldLabel.Enable = 'off';
            app.distractorEditFieldLabel.Text = 'distractor';
            app.distractorEditField = uieditfield(app.TasksettingsPanel, 'text');
            app.distractorEditField.ValueChangedFcn = createCallbackFcn(app, @distractorEditFieldValueChanged, true);
            app.distractorEditField.Editable = 'off';
            app.distractorEditField.Enable = 'off';
            app.distractorEditField.Value = '0';
            app.ModalityDropDownLabel = uilabel(app.TasksettingsPanel);
            app.ModalityDropDownLabel.Text = 'Modality';
            app.ModalityDropDown = uidropdown(app.TasksettingsPanel);
            app.ModalityDropDown.Items = {'Vision', 'Audio', 'AudioVisual', 'Somatosensory', 'SomatoVisual', 'SomatoAudio', 'AllMixed'};
            app.ModalityDropDown.ValueChangedFcn = createCallbackFcn(app, @ModalityDropDownValueChanged, true);
            app.ModalityDropDown.Value = 'Vision';
            app.Panel = uipanel(app.TasksettingsPanel);
            app.pAudioSpinnerLabel = uilabel(app.Panel);
            app.pAudioSpinnerLabel.Text = 'pAudio';
            app.pAudioSpinner = uispinner(app.Panel);
            app.pAudioSpinner.Step = 0.1;
            app.pAudioSpinner.Limits = [0 1];
            app.pAudioSpinner.ValueChangedFcn = createCallbackFcn(app, @pAudioSpinnerValueChanged, true);
            app.pVisualSpinnerLabel = uilabel(app.Panel);
            app.pVisualSpinnerLabel.Text = 'pVisual';
            app.pVisualSpinner = uispinner(app.Panel);
            app.pVisualSpinner.Step = 0.1;
            app.pVisualSpinner.Limits = [0 1];
            app.pVisualSpinner.ValueChangedFcn = createCallbackFcn(app, @pVisualSpinnerValueChanged, true);
            app.pSomatosensorySpinnerLabel = uilabel(app.Panel);
            app.pSomatosensorySpinnerLabel.Text = 'pSomatosensory';
            app.pSomatosensorySpinner = uispinner(app.Panel);
            app.pSomatosensorySpinner.Step = 0.1;
            app.pSomatosensorySpinner.Limits = [0 1];
            app.pSomatosensorySpinner.ValueChangedFcn = createCallbackFcn(app, @pSomatosensorySpinnerValueChanged, true);
            app.pDistSpinnerLabel = uilabel(app.TasksettingsPanel);
            app.pDistSpinnerLabel.Text = 'pDist';
            app.pDistSpinner = uispinner(app.TasksettingsPanel);
            app.pDistSpinner.Step = 0.25;
            app.pDistSpinner.Limits = [0 1];
            app.pDistSpinner.ValueChangedFcn = createCallbackFcn(app, @pDistSpinnerValueChanged, true);
            app.pRightSpinnerLabel = uilabel(app.TasksettingsPanel);
            app.pRightSpinnerLabel.Text = 'pRight';
            app.pRightSpinner = uispinner(app.TasksettingsPanel);
            app.pRightSpinner.Step = 0.1;
            app.pRightSpinner.Limits = [0 1];
            app.pRightSpinner.ValueChangedFcn = createCallbackFcn(app, @pRightSpinnerValueChanged, true);
            app.pRightSpinner.Value = 0.5;
            app.FrequencyLabel = uilabel(app.TasksettingsPanel);
            app.FrequencyLabel.Text = 'Frequency';
            app.StimrateSpinner = uispinner(app.TasksettingsPanel);
            app.StimrateSpinner.Limits = [0 100];
            app.StimrateSpinner.ValueChangedFcn = createCallbackFcn(app, @StimrateSpinnerValueChanged, true);
            app.StimrateSpinner.Value = 2;
            app.AudiodistractorCheckBox = uicheckbox(app.TasksettingsPanel);
            app.AudiodistractorCheckBox.ValueChangedFcn = createCallbackFcn(app, @AudiodistractorCheckBoxValueChanged, true);
            app.AudiodistractorCheckBox.Text = 'Audio distractor';
            app.VisualdistractorCheckBox = uicheckbox(app.TasksettingsPanel);
            app.VisualdistractorCheckBox.ValueChangedFcn = createCallbackFcn(app, @VisualdistractorCheckBoxValueChanged, true);
            app.VisualdistractorCheckBox.Text = 'Visual distractor';
            app.SomatossensorydistractorCheckBox = uicheckbox(app.TasksettingsPanel);
            app.SomatossensorydistractorCheckBox.ValueChangedFcn = createCallbackFcn(app, @SomatossensorydistractorCheckBoxValueChanged, true);
            app.SomatossensorydistractorCheckBox.Text = 'Somatossensory distractor';
            app.targetEditFieldLabel = uilabel(app.TasksettingsPanel);
            app.targetEditFieldLabel.Text = 'target';
            app.targetEditField = uieditfield(app.TasksettingsPanel, 'text');
            app.targetEditField.ValueChangedFcn = createCallbackFcn(app, @targetEditFieldValueChanged, true);
            app.targetEditField.Value = '0';
            app.maxDetectionSequence = uicheckbox(app.TasksettingsPanel);
            app.maxDetectionSequence.ValueChangedFcn = createCallbackFcn(app, @maxDetectionSequenceValueChanged, true);
            app.maxDetectionSequence.Text = 'Full detection sequence';
            app.maxDetectionSequence.Value = true;
            app.difficultiesEditFieldLabel = uilabel(app.TasksettingsPanel);
            app.difficultiesEditFieldLabel.Text = 'difficulties';
            app.distDifficulties = uieditfield(app.TasksettingsPanel, 'text');
            app.distDifficulties.ValueChangedFcn = createCallbackFcn(app, @distDifficultiesValueChanged, true);
            app.distDifficulties.Value = '1 2 3 4 5';
            app.TrainingPanel = uipanel(app.TaskTab);
            app.TrainingPanel.Title = 'Training';
            app.TrainingmodeCheckBox = uicheckbox(app.TrainingPanel);
            app.TrainingmodeCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrainingmodeCheckBoxValueChanged, true);
            app.TrainingmodeCheckBox.Text = 'Training mode';
            app.AutorewardCheckBox = uicheckbox(app.TrainingPanel);
            app.AutorewardCheckBox.ValueChangedFcn = createCallbackFcn(app, @AutorewardCheckBoxValueChanged, true);
            app.AutorewardCheckBox.Text = 'Auto-reward';
            app.AssistedLampLabel = uilabel(app.TrainingPanel);
            app.AssistedLampLabel.Text = 'Assisted';
            app.AssistedLamp = uilamp(app.TrainingPanel);
            app.AssistedLamp.Enable = 'off';
            app.ShowWaterCheckBox = uicheckbox(app.TrainingPanel);
            app.ShowWaterCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowWaterCheckBoxValueChanged, true);
            app.ShowWaterCheckBox.Text = 'Show water';
            app.fractionselfperformedPanel = uipanel(app.TaskTab);
            app.fractionselfperformedPanel.Title = 'fraction self-performed';
            app.fractionvisionSpinnerLabel = uilabel(app.fractionselfperformedPanel);
            app.fractionvisionSpinnerLabel.Text = 'fraction vision';
            app.fractionvisionSpinner = uispinner(app.fractionselfperformedPanel);
            app.fractionvisionSpinner.Step = 0.1;
            app.fractionvisionSpinner.Limits = [0 1];
            app.fractionvisionSpinner.ValueChangedFcn = createCallbackFcn(app, @fractionvisionSpinnerValueChanged, true);
            app.fractionaudioSpinnerLabel = uilabel(app.fractionselfperformedPanel);
            app.fractionaudioSpinnerLabel.Text = 'fraction audio';
            app.fractionaudioSpinner = uispinner(app.fractionselfperformedPanel);
            app.fractionaudioSpinner.Step = 0.1;
            app.fractionaudioSpinner.Limits = [0 1];
            app.fractionaudioSpinner.ValueChangedFcn = createCallbackFcn(app, @fractionaudioSpinnerValueChanged, true);
            app.fractiontactileSpinnerLabel = uilabel(app.fractionselfperformedPanel);
            app.fractiontactileSpinnerLabel.Text = 'fraction tactile';
            app.fractiontactileSpinner = uispinner(app.fractionselfperformedPanel);
            app.fractiontactileSpinner.Step = 0.1;
            app.fractiontactileSpinner.Limits = [0 1];
            app.fractiontactileSpinner.ValueChangedFcn = createCallbackFcn(app, @fractiontactileSpinnerValueChanged, true);
            app.fractionmixedSpinnerLabel = uilabel(app.fractionselfperformedPanel);
            app.fractionmixedSpinnerLabel.Text = 'fraction mixed';
            app.fractionmixedSpinner = uispinner(app.fractionselfperformedPanel);
            app.fractionmixedSpinner.Step = 0.1;
            app.fractionmixedSpinner.Limits = [0 1];
            app.fractionmixedSpinner.ValueChangedFcn = createCallbackFcn(app, @fractionmixedSpinnerValueChanged, true);
            app.LeftspoutbiasSpinnerLabel = uilabel(app.TaskTab);
            app.LeftspoutbiasSpinnerLabel.Text = 'Left spout bias';
            app.LeftspoutbiasSpinner = uispinner(app.TaskTab);
            app.LeftspoutbiasSpinner.Limits = [-10 20];
            app.LeftspoutbiasSpinner.ValueChangedFcn = createCallbackFcn(app, @LeftspoutbiasSpinnerValueChanged, true);
            app.RightspoutbiasSpinnerLabel = uilabel(app.TaskTab);
            app.RightspoutbiasSpinnerLabel.Text = 'Right spout bias';
            app.RightspoutbiasSpinner = uispinner(app.TaskTab);
            app.RightspoutbiasSpinner.Limits = [-10 20];
            app.RightspoutbiasSpinner.ValueChangedFcn = createCallbackFcn(app, @RightspoutbiasSpinnerValueChanged, true);
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.optogeneticsPanel = uipanel(app.SettingsTab);
            app.optogeneticsPanel.Title = 'optogenetics';
            app.fractiontrialsEditFieldLabel = uilabel(app.optogeneticsPanel);
            app.fractiontrialsEditFieldLabel.Text = 'fraction trials';
            app.optofractiontrialsEditField = uieditfield(app.optogeneticsPanel, 'numeric');
            app.optofractiontrialsEditField.Limits = [0 1];
            app.optofractiontrialsEditField.ValueChangedFcn = createCallbackFcn(app, @optofractiontrialsEditFieldValueChanged, true);
            app.durationsLabel = uilabel(app.optogeneticsPanel);
            app.durationsLabel.Text = 'duration (s)';
            app.optodurationEditField = uieditfield(app.optogeneticsPanel, 'numeric');
            app.optodurationEditField.Limits = [0 100];
            app.optodurationEditField.ValueChangedFcn = createCallbackFcn(app, @optodurationEditFieldValueChanged, true);
            app.optoperiodDropDown = uidropdown(app.optogeneticsPanel);
            app.optoperiodDropDown.Items = {'Stimulus', 'Delay', 'Stimulus/Delay', 'Response', 'AllTimes'};
            app.optoperiodDropDown.ValueChangedFcn = createCallbackFcn(app, @optoperiodDropDownValueChanged, true);
            app.optoperiodDropDown.Value = 'AllTimes';
            app.power1mWLabel = uilabel(app.optogeneticsPanel);
            app.power1mWLabel.Text = 'power1 (mW)';
            app.optoamp1EditField = uieditfield(app.optogeneticsPanel, 'numeric');
            app.optoamp1EditField.Limits = [0 20];
            app.optoamp1EditField.ValueChangedFcn = createCallbackFcn(app, @optoamp1EditFieldValueChanged, true);
            app.power2mwLabel = uilabel(app.optogeneticsPanel);
            app.power2mwLabel.Text = 'power2 (mw)';
            app.optoamp2EditField = uieditfield(app.optogeneticsPanel, 'numeric');
            app.optoamp2EditField.Limits = [0 20];
            app.optoamp2EditField.ValueChangedFcn = createCallbackFcn(app, @optoamp2EditFieldValueChanged, true);
            app.rampdownsLabel = uilabel(app.optogeneticsPanel);
            app.rampdownsLabel.Text = 'ramp down (s)';
            app.optoRampEditField = uieditfield(app.optogeneticsPanel, 'numeric');
            app.optoRampEditField.Limits = [0 5];
            app.optoRampEditField.ValueChangedFcn = createCallbackFcn(app, @optoRampEditFieldValueChanged, true);
            app.optoRampEditField.Value = 0.2;
            app.optoLocation = uidropdown(app.optogeneticsPanel);
            app.optoLocation.Items = {'A1', 'Striatum', 'Thalamus', 'SC', 'ALM'};
            app.optoLocation.ValueChangedFcn = createCallbackFcn(app, @optoLocationValueChanged, true);
            app.optoLocation.Value = 'A1';
            app.rightprobabilityLabel = uilabel(app.optogeneticsPanel);
            app.rightprobabilityLabel.Text = 'right probability';
            app.optoProbRight = uieditfield(app.optogeneticsPanel, 'numeric');
            app.optoProbRight.Limits = [0 1];
            app.optoProbRight.ValueChangedFcn = createCallbackFcn(app, @optoProbRightValueChanged, true);
            app.optoProbRight.Value = 0.5;
            app.bothprobabilityLabel = uilabel(app.optogeneticsPanel);
            app.bothprobabilityLabel.Text = 'both probability';
            app.optoProbBoth = uieditfield(app.optogeneticsPanel, 'numeric');
            app.optoProbBoth.Limits = [0 1];
            app.optoProbBoth.ValueChangedFcn = createCallbackFcn(app, @optoProbBothValueChanged, true);
            app.optoProbBoth.Value = 1;
            app.NetworktriggerPanel = uipanel(app.SettingsTab);
            app.NetworktriggerPanel.Title = 'Network trigger';
            app.widefieldaddressEditFieldLabel = uilabel(app.NetworktriggerPanel);
            app.widefieldaddressEditFieldLabel.Text = 'widefield address';
            app.widefieldaddressEditField = uieditfield(app.NetworktriggerPanel, 'text');
            app.widefieldaddressEditField.ValueChangedFcn = createCallbackFcn(app, @widefieldaddressEditFieldValueChanged, true);
            app.labcamsaddressEditFieldLabel = uilabel(app.NetworktriggerPanel);
            app.labcamsaddressEditFieldLabel.Text = 'labcams address';
            app.labcamsaddressEditField = uieditfield(app.NetworktriggerPanel, 'text');
            app.labcamsaddressEditField.ValueChangedFcn = createCallbackFcn(app, @labcamsaddressEditFieldValueChanged, true);
            app.StimulisettingsPanel = uipanel(app.SettingsTab);
            app.StimulisettingsPanel.Title = 'Stimuli settings';
            app.StimulidurationsSpinnerLabel = uilabel(app.StimulisettingsPanel);
            app.StimulidurationsSpinnerLabel.Text = 'Stimuli duration (s)';
            app.StimulidurationSpinner = uispinner(app.StimulisettingsPanel);
            app.StimulidurationSpinner.Step = 0.5;
            app.StimulidurationSpinner.Limits = [0 10];
            app.StimulidurationSpinner.ValueChangedFcn = createCallbackFcn(app, @StimulidurationSpinnerValueChanged, true);
            app.StimulidurationSpinner.Value = 1;
            app.PunishdurationLabel = uilabel(app.StimulisettingsPanel);
            app.PunishdurationLabel.Text = 'Punish duration ';
            app.PunishdurationSpinner = uispinner(app.StimulisettingsPanel);
            app.PunishdurationSpinner.Step = 0.1;
            app.PunishdurationSpinner.ValueChangingFcn = createCallbackFcn(app, @PunishdurationSpinnerValueChanging, true);
            app.PunishdurationSpinner.Limits = [0 10];
            app.PunishdurationSpinner.ValueChangedFcn = createCallbackFcn(app, @PunishdurationSpinnerValueChanged, true);
            app.PunishdurationSpinner.Value = 1;
            app.FlashdurmsSpinnerLabel = uilabel(app.StimulisettingsPanel);
            app.FlashdurmsSpinnerLabel.Text = 'Flash dur (ms)';
            app.FlashdurmsSpinner = uispinner(app.StimulisettingsPanel);
            app.FlashdurmsSpinner.Limits = [1 30];
            app.FlashdurmsSpinner.ValueChangedFcn = createCallbackFcn(app, @FlashdurmsSpinnerValueChanged, true);
            app.FlashdurmsSpinner.Value = 3;
            app.BeepdurmsSpinnerLabel = uilabel(app.StimulisettingsPanel);
            app.BeepdurmsSpinnerLabel.Text = 'Beep dur (ms)';
            app.BeepdurmsSpinner = uispinner(app.StimulisettingsPanel);
            app.BeepdurmsSpinner.Limits = [1 30];
            app.BeepdurmsSpinner.ValueChangedFcn = createCallbackFcn(app, @BeepdurmsSpinnerValueChanged, true);
            app.BeepdurmsSpinner.Value = 3;
            app.PuffdurmsSpinnerLabel = uilabel(app.StimulisettingsPanel);
            app.PuffdurmsSpinnerLabel.Text = 'Puff dur (ms)';
            app.PuffdurmsSpinner = uispinner(app.StimulisettingsPanel);
            app.PuffdurmsSpinner.Limits = [1 500];
            app.PuffdurmsSpinner.ValueChangedFcn = createCallbackFcn(app, @PuffdurmsSpinnerValueChanged, true);
            app.PuffdurmsSpinner.Value = 3;
            app.brightnessSpinnerLabel = uilabel(app.StimulisettingsPanel);
            app.brightnessSpinnerLabel.Text = 'brightness';
            app.brightnessSpinner = uispinner(app.StimulisettingsPanel);
            app.brightnessSpinner.Limits = [1 30];
            app.brightnessSpinner.ValueChangedFcn = createCallbackFcn(app, @brightnessSpinnerValueChanged, true);
            app.brightnessSpinner.Value = 10;
            app.loudnessSpinnerLabel = uilabel(app.StimulisettingsPanel);
            app.loudnessSpinnerLabel.Text = 'loudness';
            app.loudnessSpinner = uispinner(app.StimulisettingsPanel);
            app.loudnessSpinner.Limits = [0 30];
            app.loudnessSpinner.ValueChangedFcn = createCallbackFcn(app, @loudnessSpinnerValueChanged, true);
            app.loudnessSpinner.Value = 2.5;
            app.puffnessSpinnerLabel = uilabel(app.StimulisettingsPanel);
            app.puffnessSpinnerLabel.Text = 'puffness';
            app.puffnessSpinner = uispinner(app.StimulisettingsPanel);
            app.puffnessSpinner.Limits = [1 30];
            app.puffnessSpinner.ValueChangedFcn = createCallbackFcn(app, @puffnessSpinnerValueChanged, true);
            app.puffnessSpinner.Value = 5;
            app.PrestimdelaysLabel = uilabel(app.StimulisettingsPanel);
            app.PrestimdelaysLabel.Text = 'Pre stim delay (s)';
            app.prestimdelay = uispinner(app.StimulisettingsPanel);
            app.prestimdelay.Step = 0.1;
            app.prestimdelay.Limits = [0 10];
            app.prestimdelay.ValueChangedFcn = createCallbackFcn(app, @prestimdelayValueChanged, true);
            app.prestimdelay.Value = 0.5;
            app.StimCueCheckBox = uicheckbox(app.StimulisettingsPanel);
            app.StimCueCheckBox.ValueChangedFcn = createCallbackFcn(app, @StimCueCheckBoxValueChanged, true);
            app.StimCueCheckBox.Text = 'Stim Cue';
            app.Delay2valuesforminmaxEditFieldLabel = uilabel(app.StimulisettingsPanel);
            app.Delay2valuesforminmaxEditFieldLabel.Text = ' Delay (2 values for min-max)';
            app.Delay2valuesforminmaxEditField = uieditfield(app.StimulisettingsPanel, 'text');
            app.Delay2valuesforminmaxEditField.ValueChangedFcn = createCallbackFcn(app, @Delay2valuesforminmaxEditFieldValueChanged, true);
            app.Delay2valuesforminmaxEditField.Value = '0 0.5';
            app.UseblackscreenCheckBox = uicheckbox(app.StimulisettingsPanel);
            app.UseblackscreenCheckBox.ValueChangedFcn = createCallbackFcn(app, @UseblackscreenCheckBoxValueChanged, true);
            app.UseblackscreenCheckBox.Text = 'Use black screen';
            app.StimulusscreensEditFieldLabel = uilabel(app.StimulisettingsPanel);
            app.StimulusscreensEditFieldLabel.Text = 'Stimulus screens';
            app.StimulusscreensEditField = uieditfield(app.StimulisettingsPanel, 'text');
            app.StimulusscreensEditField.ValueChangedFcn = createCallbackFcn(app, @StimulusscreensEditFieldValueChanged, true);
            app.StimulusscreensEditField.Value = '3 4';
            app.contingencyReversal = uicheckbox(app.StimulisettingsPanel);
            app.contingencyReversal.ValueChangedFcn = createCallbackFcn(app, @contingencyReversalValueChanged, true);
            app.contingencyReversal.Text = 'L/R reversal';
            app.TasksettingsPanel_2 = uipanel(app.SettingsTab);
            app.TasksettingsPanel_2.Title = 'Task settings';
            app.scaleLabel = uilabel(app.TasksettingsPanel_2);
            app.scaleLabel.Text = 'scale';
            app.TimetochoosesSpinnerLabel = uilabel(app.TasksettingsPanel_2);
            app.TimetochoosesSpinnerLabel.Text = 'Time to choose (s)';
            app.ITI_scale = uispinner(app.TasksettingsPanel_2);
            app.ITI_scale.Step = 0.1;
            app.ITI_scale.Limits = [0 10];
            app.ITI_scale.ValueChangedFcn = createCallbackFcn(app, @ITI_scaleValueChanged, true);
            app.ITI_scale.Value = 1.1;
            app.TimetochooseSpinner = uispinner(app.TasksettingsPanel_2);
            app.TimetochooseSpinner.Step = 0.5;
            app.TimetochooseSpinner.Limits = [0 10];
            app.TimetochooseSpinner.ValueChangedFcn = createCallbackFcn(app, @TimetochooseSpinnerValueChanged, true);
            app.TimetochooseSpinner.Value = 3;
            app.TimetoconfirmsLabel = uilabel(app.TasksettingsPanel_2);
            app.TimetoconfirmsLabel.Text = 'Time to confirm (s)';
            app.TimetoconfirmSpinner = uispinner(app.TasksettingsPanel_2);
            app.TimetoconfirmSpinner.Step = 0.5;
            app.TimetoconfirmSpinner.Limits = [0 10];
            app.TimetoconfirmSpinner.ValueChangedFcn = createCallbackFcn(app, @TimetoconfirmSpinnerValueChanged, true);
            app.TimetoconfirmSpinner.Value = 0.5;
            app.TimetocollectrewardsLabel = uilabel(app.TasksettingsPanel_2);
            app.TimetocollectrewardsLabel.Text = 'Time to collect reward (s)';
            app.HappytimeSpinner = uispinner(app.TasksettingsPanel_2);
            app.HappytimeSpinner.Step = 0.5;
            app.HappytimeSpinner.Limits = [0 10];
            app.HappytimeSpinner.ValueChangedFcn = createCallbackFcn(app, @HappytimeSpinnerValueChanged, true);
            app.HappytimeSpinner.Value = 3;
            app.TimeoutsLabel = uilabel(app.TasksettingsPanel_2);
            app.TimeoutsLabel.Text = 'Timeout (s)';
            app.TimeoutSpinner = uispinner(app.TasksettingsPanel_2);
            app.TimeoutSpinner.Step = 0.5;
            app.TimeoutSpinner.Limits = [0 10];
            app.TimeoutSpinner.ValueChangedFcn = createCallbackFcn(app, @TimeoutSpinnerValueChanged, true);
            app.TimeoutSpinner.Value = 0.5;
            app.RewardsizeuLLabel = uilabel(app.TasksettingsPanel_2);
            app.RewardsizeuLLabel.Text = 'Reward size (uL)';
            app.rewardSizeSpinner = uispinner(app.TasksettingsPanel_2);
            app.rewardSizeSpinner.Step = 0.5;
            app.rewardSizeSpinner.Limits = [0 10];
            app.rewardSizeSpinner.ValueChangedFcn = createCallbackFcn(app, @rewardSizeSpinnerValueChanged, true);
            app.rewardSizeSpinner.Value = 1.5;
            app.IntertrialintervalLabel = uilabel(app.TasksettingsPanel_2);
            app.IntertrialintervalLabel.Text = 'Inter-trial interval';
            app.shapeLabel = uilabel(app.TasksettingsPanel_2);
            app.shapeLabel.Text = 'shape';
            app.ITI_shape = uispinner(app.TasksettingsPanel_2);
            app.ITI_shape.Step = 0.1;
            app.ITI_shape.Limits = [0 20];
            app.ITI_shape.ValueChangedFcn = createCallbackFcn(app, @ITI_shapeValueChanged, true);
            app.ITI_shape.Value = 2;
            app.TrialstartsoundCheckBox = uicheckbox(app.TasksettingsPanel_2);
            app.TrialstartsoundCheckBox.ValueChangedFcn = createCallbackFcn(app, @TrialstartsoundCheckBoxValueChanged, true);
            app.TrialstartsoundCheckBox.Text = 'Trial start sound';
            app.MaxtrialsonsamesideSpinnerLabel = uilabel(app.TasksettingsPanel_2);
            app.MaxtrialsonsamesideSpinnerLabel.Text = 'Max trials on same side';
            app.MaxtrialsonsamesideSpinner = uispinner(app.TasksettingsPanel_2);
            app.MaxtrialsonsamesideSpinner.RoundFractionalValues = 'on';
            app.MaxtrialsonsamesideSpinner.ValueDisplayFormat = '%3d';
            app.MaxtrialsonsamesideSpinner.ValueChangedFcn = createCallbackFcn(app, @MaxtrialsonsamesideSpinnerValueChanged, true);
            app.SavesettingsCheckBox = uicheckbox(app.SettingsTab);
            app.SavesettingsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavesettingsCheckBoxValueChanged, true);
            app.SavesettingsCheckBox.Text = 'Save settings';
            app.SavesettingsCheckBox.Value = true;
            app.ServerpathEditFieldLabel = uilabel(app.SettingsTab);
            app.ServerpathEditFieldLabel.Text = 'Server path';
            app.ServerpathEditField = uieditfield(app.SettingsTab, 'text');
            app.ServerpathEditField.ValueChangedFcn = createCallbackFcn(app, @ServerpathEditFieldValueChanged, true);
            app.optosequencePanel = uipanel(app.SettingsTab);
            app.optosequencePanel.Title = 'opto sequence';
            app.optoSeqActive = uicheckbox(app.optosequencePanel);
            app.optoSeqActive.ValueChangedFcn = createCallbackFcn(app, @optoSeqActiveValueChanged, true);
            app.optoSeqActive.Text = 'activate';
            app.optotrialsLabel = uilabel(app.optosequencePanel);
            app.optotrialsLabel.Text = 'opto trials';
            app.optoSeqTrials = uieditfield(app.optosequencePanel, 'numeric');
            app.optoSeqTrials.Limits = [0 Inf];
            app.optoSeqTrials.RoundFractionalValues = 'on';
            app.optoSeqTrials.ValueChangedFcn = createCallbackFcn(app, @optoSeqTrialsValueChanged, true);
            app.optoSeqTrials.Value = 5;
            app.intervalminLabel = uilabel(app.optosequencePanel);
            app.intervalminLabel.Text = 'interval (min)';
            app.optoSeqInterval = uieditfield(app.optosequencePanel, 'numeric');
            app.optoSeqInterval.Limits = [0 1000];
            app.optoSeqInterval.ValueChangedFcn = createCallbackFcn(app, @optoSeqIntervalValueChanged, true);
            app.optoSeqInterval.Value = 10;
            app.startminLabel = uilabel(app.optosequencePanel);
            app.startminLabel.Text = 'start (min)';
            app.optoSeqStartTime = uieditfield(app.optosequencePanel, 'numeric');
            app.optoSeqStartTime.Limits = [0 1000];
            app.optoSeqStartTime.ValueChangedFcn = createCallbackFcn(app, @optoSeqStartTimeValueChanged, true);
            app.optoSeqStartTime.Value = 5;
            app.optoSeqUnilateral = uicheckbox(app.optosequencePanel);
            app.optoSeqUnilateral.ValueChangedFcn = createCallbackFcn(app, @optoSeqUnilateralValueChanged, true);
            app.optoSeqUnilateral.Text = 'unilateral';
            app.Subjectname = uilabel(app.PuffyPenguinUIFigure);
            app.Subjectname.Text = 'Subject';
            app.PauseSwitch = uiswitch(app.PuffyPenguinUIFigure, 'slider');
            app.PauseSwitch.Items = {'pause', 'run'};
            app.PauseSwitch.ValueChangedFcn = createCallbackFcn(app, @PauseSwitchValueChanged, true);
            app.PauseSwitch.Value = 'pause';
            app.Trial = uilabel(app.PuffyPenguinUIFigure);
            app.Trial.Text = '';
            app.Water = uilabel(app.PuffyPenguinUIFigure);
            app.Water.Text = '';
            app.WeightgSpinnerLabel = uilabel(app.PuffyPenguinUIFigure);
            app.WeightgSpinnerLabel.Text = 'Weight (g)';
            app.WeightgSpinner = uispinner(app.PuffyPenguinUIFigure);
            app.WeightgSpinner.Step = 0.1;
            app.WeightgSpinner.Limits = [10 50];
            app.WeightgSpinner.ValueChangedFcn = createCallbackFcn(app, @WeightgSpinnerValueChanged, true);
            app.WeightgSpinner.Value = 20;
            app.StopButton = uibutton(app.PuffyPenguinUIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.timeElapsedLabel = uilabel(app.PuffyPenguinUIFigure);
            app.timeElapsedLabel.Text = 'X min';
            % Show the figure after all components are created
            app.PuffyPenguinUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PuffyPenguin_GUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.PuffyPenguinUIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.PuffyPenguinUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PuffyPenguinUIFigure)
        end
    end
end